classdef iSamakKATRINEnergyLoss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iSamakUIFigure            matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        NumberofScatteringSlider  matlab.ui.control.Slider
        AseevSwitchLabel          matlab.ui.control.Label
        AseevSwitch               matlab.ui.control.Switch
        AbdurashitovSwitchLabel   matlab.ui.control.Label
        AbdurashitovSwitch        matlab.ui.control.Switch
        KatrinT2A20SwitchLabel    matlab.ui.control.Label
        KatrinT2A20Switch         matlab.ui.control.Switch
        KatrinT2SwitchLabel       matlab.ui.control.Label
        KatrinT2Switch            matlab.ui.control.Switch
        KATRINInelasticScatteringEnergyLossFunctionsLabel  matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        EmineVEditFieldLabel      matlab.ui.control.Label
        EmineVEditField           matlab.ui.control.NumericEditField
        EmaxeVEditFieldLabel      matlab.ui.control.Label
        EmaxeVEditField           matlab.ui.control.NumericEditField
        InitButton                matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        DisplaySwitch             matlab.ui.control.Switch
        NisLabel                  matlab.ui.control.Label
        KatrinD2SwitchLabel       matlab.ui.control.Label
        KatrinD2Switch            matlab.ui.control.Switch
    end

    
    properties (Access = private)
        Aseev;                % WGTSMACE Object
        Abdurashitov;         % WGTSMACE Object
        CW_GLT;               % WGTSMACE Object
        CW_G2LT;              % WGTSMACE Object
        KatrinD2;             % WGTSMACE Object
        Nscatt;               % Number of Inelastic Scattering
        EnergyRange;          % -1000:.1:1000;
        Aseev_Array;          % Array of functions with Aseev ELoss
        Abdurashitov_Array;   % Array of functions with Abdurashitov ELoss
        CW_GLT_Array;         % Array of functions with CW_GLT ELoss
        CW_G2LT_Array;        % Array of functions with CW_G2LT ELoss
        KatrinD2_Array;       % Array of functions with KatrinD2 ELoss
        Line_Aseev;
        Line_Abdurashitov;
        Line_CW_GLT;
        Line_CW_G2LT;
        Line_KatrinD2;
    end
    
    methods (Access = private)
        
        function PlotUpdate(app)
            %% Plot inelastic scattering eloss functions
            % hold(app.UIAxes,'on')
            switch app.AseevSwitch.Value
                case 'On'
                    set(app.Line_Aseev,'Visible','on')
                case 'Off'
                    set(app.Line_Aseev,'Visible','off')
            end
            switch app.AbdurashitovSwitch.Value
                case 'On'
                    set(app.Line_Abdurashitov,'Visible','on')
                case 'Off'
                    set(app.Line_Abdurashitov,'Visible','off')
            end
            switch app.KatrinT2A20Switch.Value
                case 'On'
                    set(app.Line_CW_GLT,'Visible','on')
                case 'Off'
                    set(app.Line_CW_GLT,'Visible','off')
            end
            switch app.KatrinT2Switch.Value
                case 'On'
                    set(app.Line_CW_G2LT,'Visible','on')
                case 'Off'
                    set(app.Line_CW_G2LT,'Visible','off')
            end
            switch app.KatrinD2Switch.Value
                case 'On'
                    set(app.Line_KatrinD2,'Visible','on')
                case 'Off'
                    set(app.Line_KatrinD2,'Visible','off')
            end            
            hold(app.UIAxes,'off')
            xlim(app.UIAxes,[app.EmineVEditField.Value app.EmaxeVEditField.Value]);
            xlabel(app.UIAxes,'Energy Loss (eV)');
            ylabel(app.UIAxes,'pdf');
            app.UIAxes.YLim = [1E-4 max([app.Aseev_Array(app.EnergyRange) app.Abdurashitov_Array(app.EnergyRange) app.CW_GLT_Array(app.EnergyRange)  app.CW_G2LT_Array(app.EnergyRange) app.KatrinD2_Array(app.EnergyRange)])];
            legend(app.UIAxes,[app.Line_Aseev app.Line_Abdurashitov app.Line_CW_GLT app.Line_CW_G2LT app.Line_KatrinD2],'Aseev','Abdurashitov','KatrinT2A20','KatrinT2','KatrinD2'); % legends,
            
        end
        
        function PlotInit(app)
            app.UIAxes.cla;
            app.Line_Aseev=plot(app.UIAxes,app.EnergyRange,app.Aseev_Array(app.EnergyRange),'LineWidth',2,'Color',rgb('DarkBlue'));
            hold(app.UIAxes,'on')
            app.Line_Abdurashitov=plot(app.UIAxes,app.EnergyRange,app.Abdurashitov_Array(app.EnergyRange),'LineWidth',2,'Color',rgb('DarkGreen'));
            app.Line_CW_GLT=plot(app.UIAxes,app.EnergyRange,app.CW_GLT_Array(app.EnergyRange),'LineWidth',2,'Color',rgb('Orange'));
            app.Line_CW_G2LT=plot(app.UIAxes,app.EnergyRange,app.CW_G2LT_Array(app.EnergyRange),'LineWidth',2,'Color',rgb('IndianRed'));
            app.Line_KatrinD2=plot(app.UIAxes,app.EnergyRange,app.KatrinD2_Array(app.EnergyRange),'LineWidth',2,'Color',rgb('Amethyst'));
            hold(app.UIAxes,'off')
            app.EmineVEditField.Value=0;
            app.EmaxeVEditField.Value=50*app.Nscatt;
            xlim(app.UIAxes,[app.EmineVEditField.Value app.EmaxeVEditField.Value]);
            xlabel(app.UIAxes,'Energy Loss (eV)');
            ylabel(app.UIAxes,'pdf');
        end
        
        function PlotTBDISRatio(app)
            % TBDIS
            app.UIAxes.cla;
            ModelArg = {'BKG_RateAllFPDSec',0.364,'MACE_Ba_T',6e-4,...
                'WGTS_B_T',2.5,'MACE_Bmax_T',4.2};
            K_Aseev=ref_TBD_NominalKATRIN(ModelArg{:},'ELossFlag','Aseev','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            K_Abdurashitov=ref_TBD_NominalKATRIN(ModelArg{:},'ELossFlag','Abdurashitov','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            K_CW_GLT=ref_TBD_NominalKATRIN(ModelArg{:},'ELossFlag','CW_GLT','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            K_CW_GLT500meV=ref_TBD_NominalKATRIN(ModelArg{:},'mnuSq_i',0.25,'ELossFlag','CW_GLT','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            K_CW_G2LT=ref_TBD_NominalKATRIN(ModelArg{:},'ELossFlag','CW_G2LT','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            K_KatrinD2=ref_TBD_NominalKATRIN(ModelArg{:},'ELossFlag','KatrinD2','TD','MTDcreator_E018575.0_100eV_B35_Ba7.0_RedF0.7_NuMF0.60_BkgF0.15_B364');
            
            K_Aseev.ComputeTBDDS; K_Aseev.ComputeTBDIS;
            K_Abdurashitov.ComputeTBDDS; K_Abdurashitov.ComputeTBDIS;
            K_CW_GLT.ComputeTBDDS; K_CW_GLT.ComputeTBDIS;
            K_CW_GLT500meV.ComputeTBDDS; K_CW_GLT500meV.ComputeTBDIS;
            K_CW_G2LT.ComputeTBDDS; K_CW_G2LT.ComputeTBDIS;
            K_KatrinD2.ComputeTBDDS; K_KatrinD2.ComputeTBDIS;
            
            cw=K_CW_GLT.TBDIS./(K_Aseev.qUfrac.*K_Aseev.TimeSec);
            app.Line_Aseev=plot(app.UIAxes,K_Aseev.qU-K_Aseev.Q_i,K_Aseev.TBDIS./(K_Aseev.qUfrac.*K_Aseev.TimeSec)./cw,'LineWidth',2,'Color',rgb('DarkBlue'));
            hold(app.UIAxes,'on')
            app.Line_Abdurashitov=plot(app.UIAxes,K_Abdurashitov.qU-K_Aseev.Q_i,K_Abdurashitov.TBDIS./(K_Aseev.qUfrac.*K_Aseev.TimeSec)./cw,'LineWidth',2,'Color',rgb('DarkGreen'));
            app.Line_CW_GLT=plot(app.UIAxes,K_CW_GLT.qU-K_Aseev.Q_i,cw./cw,'LineWidth',2,'Color',rgb('Orange'));
            app.Line_CW_G2LT=plot(app.UIAxes,K_CW_G2LT.qU-K_Aseev.Q_i,K_CW_G2LT.TBDIS./(K_Aseev.qUfrac.*K_Aseev.TimeSec)./cw,'LineWidth',2,'Color',rgb('IndianRed'));
            app.Line_KatrinD2=plot(app.UIAxes,K_KatrinD2.qU-K_Aseev.Q_i,K_KatrinD2.TBDIS./(K_Aseev.qUfrac.*K_Aseev.TimeSec)./cw,'LineWidth',2,'Color',rgb('Amethyst'));
            Line_CW_GLT500meV=plot(app.UIAxes,K_CW_GLT.qU-K_Aseev.Q_i,K_CW_GLT500meV.TBDIS./K_CW_GLT.TBDIS,'LineWidth',2,'Color',rgb('Black'),'LineStyle','--');                       
            hold(app.UIAxes,'off')
            xlabel(app.UIAxes,'qU-E0 (eV)');
            ylabel(app.UIAxes,'TBDIS ratio');
            xlim(app.UIAxes,[min(K_Aseev.qU-K_Aseev.Q_i) max(K_Aseev.qU-K_Aseev.Q_i)]);
            ylim(app.UIAxes,[min([app.Line_Aseev.YData app.Line_Abdurashitov.YData app.Line_CW_G2LT.YData Line_CW_GLT500meV.YData]) max([app.Line_Aseev.YData app.Line_Abdurashitov.YData app.Line_CW_G2LT.YData Line_CW_GLT500meV.YData])]);
            legend(app.UIAxes,[app.Line_Aseev app.Line_Abdurashitov app.Line_CW_GLT app.Line_CW_G2LT Line_CW_GLT500meV app.Line_KatrinD2],'Aseev','Abdurashitov','CW_GLT','CW_G2LT','500 meV','KatrinD2'); % legends,
            switch app.AseevSwitch.Value
                case 'On'
                    set(app.Line_Aseev,'Visible','on')
                case 'Off'
                    set(app.Line_Aseev,'Visible','off')
            end
            switch app.AbdurashitovSwitch.Value
                case 'On'
                    set(app.Line_Abdurashitov,'Visible','on')
                case 'Off'
                    set(app.Line_Abdurashitov,'Visible','off')
            end
            switch app.KatrinT2A20Switch.Value
                case 'On'
                    set(app.Line_CW_GLT,'Visible','on')
                case 'Off'
                    set(app.Line_CW_GLT,'Visible','off')
            end
            switch app.KatrinT2Switch.Value
                case 'On'
                    set(app.Line_CW_G2LT,'Visible','on')
                case 'Off'
                    set(app.Line_CW_G2LT,'Visible','off')
            end
            switch app.KatrinD2.Value
                case 'On'
                    set(app.Line_KatrinD2,'Visible','on')
                case 'Off'
                    set(app.Line_KatrinD2,'Visible','off')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            app.Nscatt       = 1;
            app.EnergyRange  = -1000:.1:1000;
            % Create WGTS objects
            app.Aseev         = WGTSMACE('ELossFlag','Aseev');
            app.Abdurashitov  = WGTSMACE('ELossFlag','Abdurashitov');
            app.CW_GLT        = WGTSMACE('ELossFlag','KatrinT2A20');
            app.CW_G2LT       = WGTSMACE('ELossFlag','KatrinT2');
            app.KatrinD2      = WGTSMACE('ELossFlag','KatrinD2');
            % Init
            app.Aseev.InitializeELossFunction;
            app.Abdurashitov.InitializeELossFunction
            app.CW_GLT.InitializeELossFunction
            app.CW_G2LT.InitializeELossFunction
            app.KatrinD2.InitializeELossFunction
            % Get cells of eloss function handles
            cells_Aseev        = app.Aseev.ComputeELossFunction();
            cells_Abdurashitov = app.Abdurashitov.ComputeELossFunction();
            cells_CW_GLT       = app.CW_GLT.ComputeELossFunction();
            cells_CW_G2LT      = app.CW_G2LT.ComputeELossFunction();
            cells_KatrinD2     = app.KatrinD2.ComputeELossFunction();%WARNING
            % Get 1st inelastic scattering eloss functions
            app.Aseev_Array        = cells_Aseev{app.Nscatt};
            app.Abdurashitov_Array = cells_Abdurashitov{app.Nscatt};
            app.CW_GLT_Array       = cells_CW_GLT{app.Nscatt};
            app.CW_G2LT_Array      = cells_CW_G2LT{app.Nscatt};
            app.KatrinD2_Array     = cells_KatrinD2{app.Nscatt};
            % Plot Init
            app.PlotInit();
        end

        % Value changed function: NumberofScatteringSlider
        function NumberofScatteringSliderValueChanged(app, event)
            app.NumberofScatteringSlider.Value = round(app.NumberofScatteringSlider.Value);
            app.Nscatt = app.NumberofScatteringSlider.Value;
            % Get cells of eloss function handles
            cells_Aseev        = app.Aseev.ComputeELossFunction();
            cells_Abdurashitov = app.Abdurashitov.ComputeELossFunction();
            cells_CW_GLT       = app.CW_GLT.ComputeELossFunction();
            cells_CW_G2LT      = app.CW_G2LT.ComputeELossFunction();
            cells_KatrinD2     = app.KatrinD2.ComputeELossFunction();
            % Get 1st inelastic scattering eloss functions
            app.Aseev_Array        = cells_Aseev{app.Nscatt};
            app.Abdurashitov_Array = cells_Abdurashitov{app.Nscatt};
            app.CW_GLT_Array       = cells_CW_GLT{app.Nscatt};
            app.CW_G2LT_Array      = cells_CW_G2LT{app.Nscatt};
            app.KatrinD2_Array     = cells_KatrinD2{app.Nscatt};
            app.UIAxes.cla;
            app.PlotInit();
            app.PlotUpdate();
        end

        % Value changed function: AseevSwitch
        function AseevSwitchValueChanged(app, event)
            switch app.DisplaySwitch.Value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
            end
        end

        % Value changed function: AbdurashitovSwitch
        function AbdurashitovSwitchValueChanged(app, event)
            switch app.DisplaySwitch.Value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
            end
        end

        % Value changed function: KatrinT2A20Switch
        function KatrinT2A20SwitchValueChanged(app, event)
              switch app.DisplaySwitch.Value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
              end
        end

        % Value changed function: KatrinT2Switch
        function KatrinT2SwitchValueChanged(app, event)
            switch app.DisplaySwitch.Value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'lin'
                    app.UIAxes.YScale = 'lin';
                    app.UIAxes.YLim = [1E-3 max([app.Aseev_Array(app.EnergyRange) app.Abdurashitov_Array(app.EnergyRange) app.CW_GLT_Array(app.EnergyRange)  app.CW_G2LT_Array(app.EnergyRange) app.KatrinD2_Array(app.EnergyRange)])];
                case 'log'
                    app.UIAxes.YScale = 'log';
            app.UIAxes.YLim = [1E-3 max([app.Aseev_Array(app.EnergyRange) app.Abdurashitov_Array(app.EnergyRange) app.CW_GLT_Array(app.EnergyRange)  app.CW_G2LT_Array(app.EnergyRange) app.KatrinD2_Array(app.EnergyRange)])];
            end
        end

        % Value changed function: EmineVEditField
        function EmineVEditFieldValueChanged(app, event)
            app.PlotUpdate();
        end

        % Value changed function: EmaxeVEditField
        function EmaxeVEditFieldValueChanged(app, event)
            app.PlotUpdate();
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.startupFcn;
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged(app, event)
            value = app.DisplaySwitch.Value;
            switch value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
            end
        end

        % Value changed function: KatrinD2Switch
        function KatrinD2SwitchValueChanged(app, event)
            switch app.DisplaySwitch.Value
                case 'ELoss'
                    app.PlotInit();app.PlotUpdate();
                case 'Spectra'
                    app.PlotTBDISRatio();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iSamakUIFigure = uifigure('Visible', 'off');
            app.iSamakUIFigure.Name = 'i-Samak';
            app.UIAxes = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NumberofScatteringSlider = uislider(app.iSamakUIFigure);
            app.NumberofScatteringSlider.Limits = [1 8];
            app.NumberofScatteringSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.NumberofScatteringSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofScatteringSliderValueChanged, true);
            app.NumberofScatteringSlider.MinorTicks = [1 2 3 4 5 6 7 8];
            app.NumberofScatteringSlider.Value = 1;
            app.AseevSwitchLabel = uilabel(app.iSamakUIFigure);
            app.AseevSwitchLabel.Text = 'Aseev';
            app.AseevSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.AseevSwitch.ValueChangedFcn = createCallbackFcn(app, @AseevSwitchValueChanged, true);
            app.AseevSwitch.Value = 'On';
            app.AbdurashitovSwitchLabel = uilabel(app.iSamakUIFigure);
            app.AbdurashitovSwitchLabel.Text = 'Abdurashitov';
            app.AbdurashitovSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.AbdurashitovSwitch.ValueChangedFcn = createCallbackFcn(app, @AbdurashitovSwitchValueChanged, true);
            app.AbdurashitovSwitch.Value = 'On';
            app.KatrinT2A20SwitchLabel = uilabel(app.iSamakUIFigure);
            app.KatrinT2A20SwitchLabel.Text = 'KatrinT2A20';
            app.KatrinT2A20Switch = uiswitch(app.iSamakUIFigure, 'slider');
            app.KatrinT2A20Switch.ValueChangedFcn = createCallbackFcn(app, @KatrinT2A20SwitchValueChanged, true);
            app.KatrinT2A20Switch.Value = 'On';
            app.KatrinT2SwitchLabel = uilabel(app.iSamakUIFigure);
            app.KatrinT2SwitchLabel.Text = 'KatrinT2';
            app.KatrinT2Switch = uiswitch(app.iSamakUIFigure, 'slider');
            app.KatrinT2Switch.ValueChangedFcn = createCallbackFcn(app, @KatrinT2SwitchValueChanged, true);
            app.KatrinT2Switch.Value = 'On';
            app.KATRINInelasticScatteringEnergyLossFunctionsLabel = uilabel(app.iSamakUIFigure);
            app.KATRINInelasticScatteringEnergyLossFunctionsLabel.Text = 'KATRIN: Inelastic Scattering - Energy Loss Functions ';
            app.Switch = uiswitch(app.iSamakUIFigure, 'slider');
            app.Switch.Items = {'log', 'lin'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'lin';
            app.EmineVEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.EmineVEditFieldLabel.Text = 'E min (eV)';
            app.EmineVEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.EmineVEditField.ValueChangedFcn = createCallbackFcn(app, @EmineVEditFieldValueChanged, true);
            app.EmaxeVEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.EmaxeVEditFieldLabel.Text = 'E max (eV)';
            app.EmaxeVEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.EmaxeVEditField.ValueChangedFcn = createCallbackFcn(app, @EmaxeVEditFieldValueChanged, true);
            app.InitButton = uibutton(app.iSamakUIFigure, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.UIAxes2 = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'qU (eV)')
            ylabel(app.UIAxes2, 'Integral Spectrum Ratio')
            app.DisplaySwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.DisplaySwitch.Items = {'ELoss', 'Spectra'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged, true);
            app.DisplaySwitch.Value = 'ELoss';
            app.NisLabel = uilabel(app.iSamakUIFigure);
            app.NisLabel.Text = 'Nis';
            app.KatrinD2SwitchLabel = uilabel(app.iSamakUIFigure);
            app.KatrinD2SwitchLabel.Text = 'KatrinD2';
            app.KatrinD2Switch = uiswitch(app.iSamakUIFigure, 'slider');
            app.KatrinD2Switch.ValueChangedFcn = createCallbackFcn(app, @KatrinD2SwitchValueChanged, true);
            app.KatrinD2Switch.Value = 'On';
            % Show the figure after all components are created
            app.iSamakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iSamakKATRINEnergyLoss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iSamakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iSamakUIFigure)
        end
    end
end