classdef iSamakKATRINftFitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iSamakUIFigure                 matlab.ui.Figure
        FitButton                      matlab.ui.control.Button
        PlotFitResultsButton           matlab.ui.control.Button
        FixedParametersEditFieldLabel  matlab.ui.control.Label
        FixedParametersEditField       matlab.ui.control.EditField
        LowerqUE0binVSliderLabel       matlab.ui.control.Label
        LowerqUE0binVSlider            matlab.ui.control.Slider
        FitterSwitchLabel              matlab.ui.control.Label
        FitterSwitch                   matlab.ui.control.Switch
        EfficiencycorrectionKnobLabel  matlab.ui.control.Label
        EfficiencycorrectionKnob       matlab.ui.control.DiscreteKnob
        TextArea                       matlab.ui.control.TextArea
        NeutrinomasssquaredeV2Label    matlab.ui.control.Label
        NeutrinomasssquaredeV2EditField  matlab.ui.control.NumericEditField
        EndpointE0eVEditFieldLabel     matlab.ui.control.Label
        EndpointE0eVEditField          matlab.ui.control.NumericEditField
        BackgroundcpsLabel             matlab.ui.control.Label
        BackgroundcpsEditField         matlab.ui.control.NumericEditField
        NormalizationTritiumLabel      matlab.ui.control.Label
        NormalizationTritiumEditField  matlab.ui.control.NumericEditField
        GroundStateProbabilityLabel    matlab.ui.control.Label
        GroundStateProbabilityEditField  matlab.ui.control.NumericEditField
        InitLabel                      matlab.ui.control.Label
        FitLabel                       matlab.ui.control.Label
        FitEditField_1                 matlab.ui.control.NumericEditField
        FitEditField_2                 matlab.ui.control.NumericEditField
        FitEditField_3                 matlab.ui.control.NumericEditField
        FitEditField_4                 matlab.ui.control.NumericEditField
        FitEditField_5                 matlab.ui.control.NumericEditField
        ErrorLabel                     matlab.ui.control.Label
        FitEditField_6                 matlab.ui.control.NumericEditField
        FitEditField_7                 matlab.ui.control.NumericEditField
        FitEditField_8                 matlab.ui.control.NumericEditField
        FitEditField_9                 matlab.ui.control.NumericEditField
        FitEditField_10                matlab.ui.control.NumericEditField
        chisquareEditFieldLabel        matlab.ui.control.Label
        chisquareEditField             matlab.ui.control.NumericEditField
        DOFEditFieldLabel              matlab.ui.control.Label
        DOFEditField                   matlab.ui.control.NumericEditField
        Fix1                           matlab.ui.control.CheckBox
        FixLabel                       matlab.ui.control.Label
        PullLabel                      matlab.ui.control.Label
        Fix2                           matlab.ui.control.CheckBox
        Fix3                           matlab.ui.control.CheckBox
        Fix4                           matlab.ui.control.CheckBox
        Fix5                           matlab.ui.control.CheckBox
        InitializationButton           matlab.ui.control.Button
        NuMassPullEditField            matlab.ui.control.NumericEditField
        eV2Label                       matlab.ui.control.Label
        SystematicsSwitchLabel         matlab.ui.control.Label
        SystematicsSwitch              matlab.ui.control.Switch
        UIAxes                         matlab.ui.control.UIAxes
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        DTEditField                    matlab.ui.control.Label
        DTEditFieldCell                matlab.ui.control.NumericEditField
        TTEditField                    matlab.ui.control.Label
        TTEditFieldCell                matlab.ui.control.NumericEditField
        HTEditField                    matlab.ui.control.Label
        HTEditFieldCell                matlab.ui.control.NumericEditField
        rhoDEditFieldLabel             matlab.ui.control.Label
        rhoDEditFieldCell              matlab.ui.control.NumericEditField
        secondLabel                    matlab.ui.control.Label
        molcm2Label                    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        PlotResidualsButton            matlab.ui.control.Button
        pvalueEditFieldLabel           matlab.ui.control.Label
        pvalueEditField                matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        mLabel                         matlab.ui.control.Label
        E0Label                        matlab.ui.control.Label
        BLabel                         matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        PgsLabel                       matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Switch                         matlab.ui.control.RockerSwitch
        RunDropDownLabel               matlab.ui.control.Label
        RunDropDown                    matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        A;               % Run  Analysis Object
        FixParStr1 = ''; % Fix Parameter 1 String
        FixParStr2 = ''; % Fix Parameter 2 String
        FixParStr3 = ''; % Fix Parameter 3 String
        FixParStr4 = ''; % Fix Parameter 4 String
        FixParStr5 = ''; % Fix Parameter 5 6 String
        chi2='chi2Stat'; % Type of statistics
        Pulls;           % Pulls Vector
        indexqUFT =  [1602  1402  1204  1002   804   602   402   304   202   177   154   129   104    92    82    72    62    52    45    32    22    15     2    -6   -18   -28];
    end
    
    methods (Access = private)
        
        function PlotFit_DataModel(app)
            % Update Plot
            ResidualsFlag = 'Norm';
            Mode          = 'Count';
            
            switch app.chi2
                case {'chi2Stat','chi2P'}
                    PlotCM = diag(app.A.RunData.TBDIS(:));
                case {'chi2CM','chi2CMFrac'}
                    PlotCM = app.A.FitCM;
                case 'chi2CMShape'
                    PlotCM = app.A.FitCMShape;
            end
            
            clear lfit hdata myleg;
            switch Mode
                case 'Rate'
                    %lfit = plot(app.UIAxes,app.A.ModelObj.qU-app.A.ModelObj.Q_i,app.A.ModelObj.TBDIS./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec,'Color','Blue');
                    [lfit , ~] = app.boundedlinegui(app.iSamakUIFigure,app.UIAxes,app.A.ModelObj.qU-app.A.ModelObj.Q_i,app.A.ModelObj.TBDIS./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec,diag(sqrt(PlotCM)./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec),'alpha','cmap',rgb('CadetBlue'));
                    
                    hold(app.UIAxes,'on');
                    hdata = errorbar(app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,app.A.RunData.TBDIS./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec,sqrt(app.A.RunData.TBDIS)./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec,'ko','MarkerSize',3,'MarkerFaceColor',rgb('Sienna'),'LineWidth',1);
                    hold(app.UIAxes,'off');
                case 'Count'
                    %lfit=plot(app.UIAxes,app.A.ModelObj.qU-app.A.ModelObj.Q_i,app.A.ModelObj.TBDIS,'Color','Blue');
                    [lfit , ~] = app.boundedlinegui(app.iSamakUIFigure,app.UIAxes,app.A.ModelObj.qU-app.A.ModelObj.Q_i,app.A.ModelObj.TBDIS,diag(sqrt(PlotCM)),'alpha','cmap',rgb('CadetBlue'));
                    
                    hold(app.UIAxes,'on');
                    hdata = errorbar(app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,app.A.RunData.TBDIS,sqrt(app.A.RunData.TBDIS),'ko','MarkerSize',3,'MarkerFaceColor',rgb('Sienna'),'LineWidth',1);
                    hold(app.UIAxes,'off');
            end
            
            %legend + title
            myleg = legend(app.UIAxes,[hdata lfit],'data','fit','Location','NorthEast') ;
            myleg.FontSize = 14;
            legend(app.UIAxes,'boxoff')
            
            xlim(app.UIAxes,[min(app.A.ModelObj.qU(app.A.exclDataStart:end))-app.A.ModelObj.Q_i max(app.A.ModelObj.qU)-app.A.ModelObj.Q_i]);
            switch Mode
                case 'Rate'
                    ylim(app.UIAxes,[0.75*min(app.A.ModelObj.TBDIS./app.A.ModelObj.qUfrac./app.A.ModelObj.TimeSec) 1.25*app.A.ModelObj.TBDIS(app.A.exclDataStart)./app.A.ModelObj.qUfrac(app.A.exclDataStart)./app.A.ModelObj.TimeSec]);
            end
            
            switch Mode
                case 'Rate'
                    ylabel(app.UIAxes,'counts per second');
                case 'Count'
                    ylabel(app.UIAxes,'counts');
            end
            
            set(app.UIAxes,'YMinorTick','off');
            set(app.UIAxes,'XMinorTick','off');
            set(app.UIAxes,'TickLength',[0.01 0.01]);
            set(app.UIAxes,'FontSize',14);
            
        end
        
        function PlotFit_Residual(app)
            set(app.UIAxes,'yscale','lin');
            
            % Update Plot
            ResidualsFlag = 'Norm';
            Mode          = 'Count';
            
            switch app.chi2
                case {'chi2Stat','chi2P'}
                    PlotCM = diag(app.A.RunData.TBDIS(:));
                case {'chi2CM','chi2CMFrac'}
                    PlotCM = app.A.FitCM;
                case 'chi2CMShape'
                    PlotCM = app.A.FitCMShape;
            end
            
            switch ResidualsFlag
                case 'Norm' %normalized Residuals
                    
                    [lstat , pstat]  = app.boundedlinegui(app.iSamakUIFigure,app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,zeros(numel(app.A.RunData.qU),1),sqrt(app.A.RunData.TBDIS(:))./sqrt(diag(PlotCM)),'alpha','transparency', 0.4,'cmap',rgb('SteelBlue'));%[0 0 0]);%0.8*[0 0 0]);
                    lstat.LineStyle= '--';
                    
                    hold(app.UIAxes,'on');
                    if ~strcmp(app.A.chi2,'chi2Stat')
                        [lsys,psys] = app.boundedlinegui(app.iSamakUIFigure,app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,zeros(numel(app.A.RunData.qU),1),ones(numel(app.A.RunData.qU),1),'alpha','transparency',0.4,'cmap',rgb('DarkCyan'));
                        lsys.LineStyle= '--';
                    end
                    
                    plot(app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,(app.A.RunData.TBDIS-app.A.ModelObj.TBDIS)./sqrt(diag(PlotCM)),'s','MarkerEdgeColor','k','MarkerFaceColor',rgb('Sienna') );
                    if ~strcmp(app.A.chi2,'chi2Stat')
                        legend(app.UIAxes,[pstat psys],'stat','stat+sys','Location','NorthWest'); %hsyst
                    elseif strcmp(app.A.chi2,'chi2Stat')
                        legend(app.UIAxes,pstat,'stat','stat+sys','Location','NorthWest'); %hsyst
                    end
                    
                    legend(app.UIAxes,'boxoff')
                    hold(app.UIAxes,'off');
                    
                    xlabel(app.UIAxes,sprintf('retarding potential - %.1f (V)',app.A.ModelObj.Q_i));
                    ylabel(app.UIAxes,'norm. residuals');
                    xlim(app.UIAxes,[min(app.A.ModelObj.qU(app.A.exclDataStart:end))-app.A.ModelObj.Q_i max(app.A.ModelObj.qU)-app.A.ModelObj.Q_i]);
                    set(app.UIAxes,'YMinorTick','off');
                    set(app.UIAxes,'XMinorTick','off');
                    set(app.UIAxes,'TickLength',[0.01 0.01]);
                    set(app.UIAxes,'FontSize',14);
                    
                case 'ON' % not normalized Residuals
                    
                    Residuals = (app.A.RunData.TBDIS-app.A.ModelObj.TBDIS);
                    if ~strcmp(app.A.chi2,'chi2Stat')
                        [lsys,psys] = app.boundedlinegui(app.iSamakUIFigure,app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,Residuals,sqrt(diag(PlotCM-app.A.RunData.TBDIS)),'alpha','transparency',0.4,'cmap',rgb('DarkCyan'));
                        lsys.LineStyle= '--';
                    elseif strcmp(app.A.chi2,'chi2Stat')
                        plot(app.A.RunData.qU-app.A.ModelObj.Q_i,zeros(app.A.ModelObj.nqU,1),'--','Color',rgb('DarkCyan'));
                    end
                    hold(app.UIAxes,'on');
                    pResi = errorbar(app.UIAxes,app.A.RunData.qU-app.A.ModelObj.Q_i,Residuals,sqrt(app.A.RunData.TBDIS),...
                        'o','Color',rgb('Black'));
                    if ~strcmp(app.A.chi2,'chi2Stat')
                        legend(app.UIAxes,[pResi psys],'stat','sys (CM)','Location','NorthWest'); %hsyst
                    elseif strcmp(app.A.chi2,'chi2Stat')
                        legend(app.UIAxes,pResi,'stat','Location','NorthWest'); %hsyst
                    end
                    
                    %legend(app.UIAxes,'boxoff')
                    hold(app.UIAxes,'off');
                    xlabel(app.UIAxes,sprintf('retarding potential - %.1f (V)',app.A.ModelObj.Q_i));
                    ylabel(app.UIAxes,'residuals');
                    xlim(app.UIAxes,[min(app.A.ModelObj.qU(app.A.exclDataStart:end))-app.A.ModelObj.Q_i max(app.A.ModelObj.qU)-app.A.ModelObj.Q_i]);
                    set(app.UIAxes,'YMinorTick','off');
                    set(app.UIAxes,'XMinorTick','off');
                    set(app.UIAxes,'TickLength',[0.01 0.01]);
                    set(app.UIAxes,'FontSize',14);
                    
            end
        end
        
        function PlotFit_CorrelationMatrix(app)
            app.corplotgui(app.UIAxes2, app.A.FitResult.errmat(1:5,1:5) );
            disp(app.A.FitResult.errmat(1:5,1:5))
        end
        
        function varargout = boundedlinegui(app,fig,ax,varargin)
            
            %BOUNDEDLINE Plot a line with shaded error/confidence bounds
            %
            % [hl, hp] = boundedline(x, y, b)
            % [hl, hp] = boundedline(x, y, b, linespec)
            % [hl, hp] = boundedline(x1, y1, b1, linespec1,  x2, y2, b2, linespec2)
            % [hl, hp] = boundedline(..., 'alpha')
            % [hl, hp] = boundedline(..., ax)
            % [hl, hp] = boundedline(..., 'transparency', trans)
            % [hl, hp] = boundedline(..., 'orientation', orient)
            % [hl, hp] = boundedline(..., 'nan', nanflag)
            % [hl, hp] = boundedline(..., 'cmap', cmap)
            %
            % Input variables:
            %
            %   x, y:       x and y values, either vectors of the same length, matrices
            %               of the same size, or vector/matrix pair where the row or
            %               column size of the array matches the length of the vector
            %               (same requirements as for plot function).
            %
            %   b:          npoint x nside x nline array.  Distance from line to
            %               boundary, for each point along the line (dimension 1), for
            %               each side of the line (lower/upper or left/right, depending
            %               on orientation) (dimension 2), and for each plotted line
            %               described by the preceding x-y values (dimension 3).  If
            %               size(b,1) == 1, the bounds will be the same for all points
            %               along the line.  If size(b,2) == 1, the bounds will be
            %               symmetrical on both sides of the lines.  If size(b,3) == 1,
            %               the same bounds will be applied to all lines described by
            %               the preceding x-y arrays (only applicable when either x or
            %               y is an array).  Bounds cannot include Inf, -Inf, or NaN,
            %
            %   linespec:   line specification that determines line type, marker
            %               symbol, and color of the plotted lines for the preceding
            %               x-y values.
            %
            %   'alpha':    if included, the bounded area will be rendered with a
            %               partially-transparent patch the same color as the
            %               corresponding line(s).  If not included, the bounded area
            %               will be an opaque patch with a lighter shade of the
            %               corresponding line color.
            %
            %   ax:         handle of axis where lines will be plotted.  If not
            %               included, the current axis will be used.
            %
            %   transp:     Scalar between 0 and 1 indicating with the transparency or
            %               intensity of color of the bounded area patch. Default is
            %               0.2.
            %
            %   orient:     direction to add bounds
            %               'vert':   add bounds in vertical (y) direction (default)
            %               'horiz':  add bounds in horizontal (x) direction
            %
            %   nanflag:    Sets how NaNs in the boundedline patch should be handled
            %               'fill':   fill the value based on neighboring values,
            %                         smoothing over the gap
            %               'gap':    leave a blank space over/below the line
            %               'remove': drop NaNs from patches, creating a linear
            %                         interpolation over the gap.  Note that this
            %                         applies only to the bounds; NaNs in the line will
            %                         remain.
            %
            %   cmap:       n x 3 colormap array.  If included, lines will be colored
            %               (in order of plotting) according to this colormap,
            %               overriding any linespec or default colors.
            %
            % Output variables:
            %
            %   hl:         handles to line objects
            %
            %   hp:         handles to patch objects
            %
            % Example:
            %
            % x = linspace(0, 2*pi, 50);
            % y1 = sin(x);
            % y2 = cos(x);
            % e1 = rand(size(y1))*.5+.5;
            % e2 = [.25 .5];
            %
            % ax(1) = subplot(2,2,1);
            % [l,p] = boundedline(x, y1, e1, '-b*', x, y2, e2, '--ro');
            % outlinebounds(l,p);
            % title('Opaque bounds, with outline');
            %
            % ax(2) = subplot(2,2,2);
            % boundedline(x, [y1;y2], rand(length(y1),2,2)*.5+.5, 'alpha');
            % title('Transparent bounds');
            %
            % ax(3) = subplot(2,2,3);
            % boundedline([y1;y2], x, e1(1), 'orientation', 'horiz')
            % title('Horizontal bounds');
            %
            % ax(4) = subplot(2,2,4);
            % boundedline(x, repmat(y1, 4,1), permute(0.5:-0.1:0.2, [3 1 2]), ...
            %             'cmap', cool(4), 'transparency', 0.5);
            % title('Multiple bounds using colormap');
            
            
            % Copyright 2010 Kelly Kearney
            
            %--------------------
            % Parse input
            %--------------------
            
            % Alpha flag
            
            isalpha = cellfun(@(x) ischar(x) && strcmp(x, 'alpha'), varargin);
            if any(isalpha)
                usealpha = true;
                varargin = varargin(~isalpha);
            else
                usealpha = false;
            end
            
            % Axis
            
            isax = cellfun(@(x) isscalar(x) && ishandle(x) && strcmp('axes', get(x,'type')), varargin);
            if any(isax)
                hax = varargin{isax};
                varargin = varargin(~isax);
            else
                hax = ax;
            end
            
            % Transparency
            
            [found, trans, varargin] = app.parseparam(varargin, 'transparency');
            
            if ~found
                trans = 0.2;
            end
            
            if ~isscalar(trans) || trans < 0 || trans > 1
                error('Transparency must be scalar between 0 and 1');
            end
            
            % Orientation
            
            [found, orient, varargin] = app.parseparam(varargin, 'orientation');
            
            if ~found
                orient = 'vert';
            end
            
            if strcmp(orient, 'vert')
                isvert = true;
            elseif strcmp(orient, 'horiz')
                isvert = false;
            else
                error('Orientation must be ''vert'' or ''horiz''');
            end
            
            
            % Colormap
            
            [hascmap, cmap, varargin] = app.parseparam(varargin, 'cmap');
            
            
            % NaN flag
            
            [found, nanflag, varargin] = app.parseparam(varargin, 'nan');
            if ~found
                nanflag = 'fill';
            end
            if ~ismember(nanflag, {'fill', 'gap', 'remove'})
                error('Nan flag must be ''fill'', ''gap'', or ''remove''');
            end
            
            % X, Y, E triplets, and linespec
            
            [x,y,err,linespec] = deal(cell(0));
            while ~isempty(varargin)
                if length(varargin) < 3
                    error('Unexpected input: should be x, y, bounds triplets');
                end
                if all(cellfun(@isnumeric, varargin(1:3)))
                    x = [x varargin(1)];
                    y = [y varargin(2)];
                    err = [err varargin(3)];
                    varargin(1:3) = [];
                else
                    error('Unexpected input: should be x, y, bounds triplets');
                end
                if ~isempty(varargin) && ischar(varargin{1})
                    linespec = [linespec varargin(1)];
                    varargin(1) = [];
                else
                    linespec = [linespec {[]}];
                end
            end
            
            %--------------------
            % Reformat x and y
            % for line and patch
            % plotting
            %--------------------
            
            % Calculate y values for bounding lines
            
            plotdata = cell(0,7);
            
            % Titi
            %htemp = fig;
            %figure(app.UIAxes,'visible', 'off');
            
            for ix = 1:length(x)
                
                % Get full x, y, and linespec data for each line (easier to let plot
                % check for properly-sized x and y and expand values than to try to do
                % it myself)
                
                try
                    if isempty(linespec{ix})
                        hltemp = plot(ax,x{ix}, y{ix});
                    else
                        hltemp = plot(ax,x{ix}, y{ix}, linespec{ix});
                    end
                catch
                    %    close(htemp);
                    error('X and Y matrices and/or linespec not appropriate for line plot');
                end
                
                linedata = get(hltemp, {'xdata', 'ydata', 'marker', 'linestyle', 'color'});
                
                nline = size(linedata,1);
                
                % Expand bounds matrix if necessary
                
                if nline > 1
                    if ndims(err{ix}) == 3
                        err2 = squeeze(num2cell(err{ix},[1 2]));
                    else
                        err2 = repmat(err(ix),nline,1);
                    end
                else
                    err2 = err(ix);
                end
                
                % Figure out upper and lower bounds
                
                [lo, hi] = deal(cell(nline,1));
                for iln = 1:nline
                    
                    x2 = linedata{iln,1};
                    y2 = linedata{iln,2};
                    nx = length(x2);
                    
                    if isvert
                        lineval = y2;
                    else
                        lineval = x2;
                    end
                    
                    sz = size(err2{iln});
                    
                    if isequal(sz, [nx 2])
                        lo{iln} = lineval - err2{iln}(:,1)';
                        hi{iln} = lineval + err2{iln}(:,2)';
                    elseif isequal(sz, [nx 1])
                        lo{iln} = lineval - err2{iln}';
                        hi{iln} = lineval + err2{iln}';
                    elseif isequal(sz, [1 2])
                        lo{iln} = lineval - err2{iln}(1);
                        hi{iln} = lineval + err2{iln}(2);
                    elseif isequal(sz, [1 1])
                        lo{iln} = lineval - err2{iln};
                        hi{iln} = lineval + err2{iln};
                    elseif isequal(sz, [2 nx]) % not documented, but accepted anyways
                        lo{iln} = lineval - err2{iln}(:,1);
                        hi{iln} = lineval + err2{iln}(:,2);
                    elseif isequal(sz, [1 nx]) % not documented, but accepted anyways
                        lo{iln} = lineval - err2{iln};
                        hi{iln} = lineval + err2{iln};
                    elseif isequal(sz, [2 1]) % not documented, but accepted anyways
                        lo{iln} = lineval - err2{iln}(1);
                        hi{iln} = lineval + err2{iln}(2);
                    else
                        error('Error bounds must be npt x nside x nline array');
                    end
                    
                end
                
                % Combine all data (xline, yline, marker, linestyle, color, lower bound
                % (x or y), upper bound (x or y)
                
                plotdata = [plotdata; linedata lo hi];
                
            end
            %close(htemp);
            
            % Override colormap
            
            if hascmap
                nd = size(plotdata,1);
                cmap = repmat(cmap, ceil(nd/size(cmap,1)), 1);
                cmap = cmap(1:nd,:);
                plotdata(:,5) = num2cell(cmap,2);
            end
            
            
            %--------------------
            % Plot
            %--------------------
            
            % Setup of x and y, plus line and patch properties
            
            nline = size(plotdata,1);
            [xl, yl, xp, yp, marker, lnsty, lncol, ptchcol, alpha] = deal(cell(nline,1));
            
            for iln = 1:nline
                xl{iln} = plotdata{iln,1};
                yl{iln} = plotdata{iln,2};
                %     if isvert
                %         xp{iln} = [plotdata{iln,1} fliplr(plotdata{iln,1})];
                %         yp{iln} = [plotdata{iln,6} fliplr(plotdata{iln,7})];
                %     else
                %         xp{iln} = [plotdata{iln,6} fliplr(plotdata{iln,7})];
                %         yp{iln} = [plotdata{iln,2} fliplr(plotdata{iln,2})];
                %     end
                
                [xp{iln}, yp{iln}] = app.calcpatch(plotdata{iln,1}, plotdata{iln,2}, isvert, plotdata{iln,6}, plotdata{iln,7}, nanflag);
                
                marker{iln} = plotdata{iln,3};
                lnsty{iln} = plotdata{iln,4};
                
                if usealpha
                    lncol{iln} = plotdata{iln,5};
                    ptchcol{iln} = plotdata{iln,5};
                    alpha{iln} = trans;
                else
                    lncol{iln} = plotdata{iln,5};
                    ptchcol{iln} = interp1([0 1], [1 1 1; lncol{iln}], trans);
                    alpha{iln} = 1;
                end
            end
            
            % Plot patches and lines
            
            if verLessThan('matlab', '8.4.0')
                [hp,hl] = deal(zeros(nline,1));
            else
                [hp,hl] = deal(gobjects(nline,1));
            end
            
            
            for iln = 1:nline
                hp(iln) = patch(xp{iln}, yp{iln}, ptchcol{iln}, 'facealpha', alpha{iln}, 'edgecolor', 'none', 'parent', hax);
            end
            
            for iln = 1:nline
                hl(iln) = line(xl{iln}, yl{iln}, 'marker', marker{iln}, 'linestyle', lnsty{iln}, 'color', lncol{iln}, 'parent', hax);
            end
            
            %--------------------
            % Assign output
            %--------------------
            
            nargoutchk(0,2);
            
            if nargout >= 1
                varargout{1} = hl;
            end
            
            if nargout == 2
                varargout{2} = hp;
            end
        end
        
        function [ h cor ] = corplotgui(app,ax, cov , flag )
            CC=squeeze(cov);
            SS=sqrt(abs(diag(CC)));
            cor=CC./(SS*SS');
            n = size(cov,1)+1;
            if nargin==2
                if flag
                    hh = imagesc(ax,cor);
                else
                    hh = pcolor(ax,1:n,1:n,[cor zeros(n-1,1); zeros(1,n)]);
                    %axis(ax,'ij');
                    axis(ax,'square');
                end
            else
                hh = pcolor(ax,1:n,1:n,[cor zeros(n-1,1); zeros(1,n)]);
                %axis(ax,'ij');
                axis(ax,'square');
            end
            set(ax,'XAxisLocation','top');
            set(ax,'XTick',.5+(1:(n-1)),'XTickLabel',1:(n-1));
            set(ax,'YTick',.5+(1:(n-1)),'YTickLabel',1:(n-1));
            set(ax,'TickLength',[0 0]);
            caxis(ax,[-1 1]); cm=0:1/30:1; my_cm_blue=[1-cm;1-cm;ones(size(cm))]';my_cm_red=[ones(size(cm));1-cm;1-cm]';
            colormap(ax,[flipud(my_cm_blue);[1 1 1];my_cm_red]);
            colorbar(ax);
            
            if nargout ~= 0
                h = hh;
            end
            
        end
    end
    
    methods (Access = public)
        
        function [found, val, vars] = parseparam(app,vars, param)
            
            isvar = cellfun(@(x) ischar(x) && strcmpi(x, param), vars);
            
            if sum(isvar) > 1
                error('Parameters can only be passed once');
            end
            
            if any(isvar)
                found = true;
                idx = find(isvar);
                val = vars{idx+1};
                vars([idx idx+1]) = [];
            else
                found = false;
                val = [];
            end
        end
        
        function [xp, yp] = calcpatch(app,xl, yl, isvert, lo, hi, nanflag)
            
            ismissing = isnan([xl;yl;lo;hi]);
            
            % If gap method, split
            
            if any(ismissing(:)) && strcmp(nanflag, 'gap')
                
                tmp = [xl;yl;lo;hi];
                
                idx = find(any(ismissing,1));
                n = diff([0 idx length(xl)]);
                
                tmp = mat2cell(tmp, 4, n);
                isemp = cellfun('isempty', tmp);
                tmp = tmp(~isemp);
                
                tmp = cellfun(@(a) a(:,~any(isnan(a),1)), tmp, 'uni', 0);
                isemp = cellfun('isempty', tmp);
                tmp = tmp(~isemp);
                
                xl = cellfun(@(a) a(1,:), tmp, 'uni', 0);
                yl = cellfun(@(a) a(2,:), tmp, 'uni', 0);
                lo = cellfun(@(a) a(3,:), tmp, 'uni', 0);
                hi = cellfun(@(a) a(4,:), tmp, 'uni', 0);
            else
                xl = {xl};
                yl = {yl};
                lo = {lo};
                hi = {hi};
            end
            
            [xp, yp] = deal(cell(size(xl)));
            
            for ii = 1:length(xl)
                
                iseq = ~verLessThan('matlab', '8.4.0') && isequal(lo{ii}, hi{ii}); % deal with zero-width bug in R2014b/R2015a
                
                if isvert
                    if iseq
                        xp{ii} = [xl{ii} nan(size(xl{ii}))];
                        yp{ii} = [lo{ii} fliplr(hi{ii})];
                    else
                        xp{ii} = [xl{ii} fliplr(xl{ii})];
                        yp{ii} = [lo{ii} fliplr(hi{ii})];
                    end
                else
                    if iseq
                        xp{ii} = [lo{ii} fliplr(hi{ii})];
                        yp{ii} = [yl{ii} nan(size(yl{ii}))];
                    else
                        xp{ii} = [lo{ii} fliplr(hi{ii})];
                        yp{ii} = [yl{ii} fliplr(yl{ii})];
                    end
                end
                
                if strcmp(nanflag, 'fill')
                    xp{ii} = inpaint_nans(xp{ii}', 4);
                    yp{ii} = inpaint_nans(yp{ii}', 4);
                    if iseq % need to maintain NaNs for zero-width bug
                        nx = length(xp{ii});
                        xp{ii}((nx/2)+1:end) = NaN;
                    end
                elseif strcmp(nanflag, 'remove')
                    if iseq
                        nx = length(xp{ii});
                        keepnan = false(size(xp));
                        keepnan((nx/2)+1:end) = true;
                        isn = (isnan(xp{ii}) | isnan(yp{ii})) & ~keepnan;
                    else
                        isn = isnan(xp{ii}) | isnan(yp{ii});
                    end
                    xp{ii} = xp{ii}(~isn);
                    yp{ii} = yp{ii}(~isn);
                end
                
            end
            
            if strcmp(nanflag, 'gap')
                [xp, yp] = singlepatch(xp, yp);
            else
                xp = xp{1};
                yp = yp{1};
            end
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set Button Status
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
            
            % Display Run Info
            % app.RunDropDown.Value        = '40669';
            % Miscellaneous
            app.SystematicsSwitch.Value  = 'Off';
            app.chi2                     = 'chi2Stat';
            app.LowerqUE0binVSlider.Value         = 7;
            app.FitterSwitch.Value             = 'minuit';
            app.EfficiencycorrectionKnob.Value = 'ROI+PileUp';
            % Fit Results
            app.FitEditField_1.Value  = 0;
            app.FitEditField_2.Value  = 0;
            app.FitEditField_3.Value  = 0;
            app.FitEditField_4.Value  = 0;
            app.FitEditField_5.Value  = 0;
            app.FitEditField_6.Value  = 0;
            app.FitEditField_7.Value  = 0;
            app.FitEditField_8.Value  = 0;
            app.FitEditField_9.Value  = 0;
            app.FitEditField_10.Value = 0;
            % Fixed Parameters
            app.Fix1.Value = true;
            app.Fix2.Value = false;
            app.Fix3.Value = false;
            app.Fix4.Value = false;
            app.Fix5.Value = true;
            if app.Fix1.Value
                app.FixParStr1 = ' 1';
            end
            if app.Fix2.Value
                app.FixParStr2 = ' 2';
            end
            if app.Fix3.Value
                app.FixParStr3 = ' 3';
            end
            if app.Fix4.Value
                app.FixParStr4 = ' 4';
            end
            if app.Fix5.Value
                app.FixParStr5 = ' 5 6';
            end
            app.chisquareEditField.Value = 0;
            app.DOFEditField.Value       = 0;
            % Pulls
            app.NuMassPullEditField.Value = Inf;
            app.Pulls(1) = Inf;
            %
            app.A = MultiRunAnalysis('RunList',str2num(app.RunDropDown.Value),...
                'AnaFlag','StackPixel',...
                'fitter',app.FitterSwitch.Value,...
                'fixPar',app.FixedParametersEditField.Value,...
                'AnaFlag','StackPixel',...
                'chi2',app.chi2,...
                'ringCutFlag','ex2',...
                'i_mnu',0,...
                'i_Q',0,...
                'i_B',0,...
                'i_N',0,...
                'i_DTGS',0,...
                'exclDataStart',round(app.LowerqUE0binVSlider.Value),...
                'DataEffCorr',app.EfficiencycorrectionKnob.Value,...
                'pulls',app.Pulls);
            % Fit Init Parameters
            app.NeutrinomasssquaredeV2EditField.Value  = app.A.ModelObj.mnuSq_i;
            app.EndpointE0eVEditField.Value              = app.A.ModelObj.Q_i;
            app.BackgroundcpsEditField.Value           = app.A.ModelObj.BKG_RateSec_i;
            app.NormalizationTritiumEditField.Value    = 1;
            app.GroundStateProbabilityEditField.Value  = app.A.ModelObj.DTNormGS_i;
            % Display Run Info
            app.rhoDEditFieldCell.Value      = app.A.RunData.WGTS_CD_MolPerCm2;
            app.TimeEditField.Value          = app.A.RunData.TimeSec;
            app.DTEditFieldCell.Value        = app.A.RunData.WGTS_MolFrac_DT*100;
            app.TTEditFieldCell.Value        = app.A.RunData.WGTS_MolFrac_TT*100;
            app.HTEditFieldCell.Value        = app.A.RunData.WGTS_MolFrac_HT*100;
            % Plot
            xlabel(app.UIAxes,sprintf('retarding potential - %.1f (V)',app.A.ModelObj.Q_i));
            Mode = 'Count';
            switch Mode
                case 'Rate'
                    ylabel(app.UIAxes,'counts per second');
                case 'Count'
                    ylabel(app.UIAxes,'counts');
            end
            set(app.UIAxes,'yscale','log');
            set(app.UIAxes,'FontSize',14);
            set(app.UIAxes,'YMinorTick','off');
            set(app.UIAxes,'TickLength',[0.01 0.01]);
            xlim(app.UIAxes,[min(app.A.ModelObj.qU(app.A.exclDataStart:end))-app.A.ModelObj.Q_i max(app.A.ModelObj.qU)-app.A.ModelObj.Q_i]);
            if isdeployed
                % Code that runs only in standalone program
            else
                % Code that runs only in development environment (MATLAB)
                app.FitButtonPushed();
            end
            
        end

        % Callback function: FitButton, FitEditField_1, iSamakUIFigure
        function FitButtonPushed(app, event)
            
            % Display Fixed Parameters
            app.FixedParametersEditField.Value = [app.FixParStr1 app.FixParStr2 app.FixParStr3 app.FixParStr4 app.FixParStr5];
            
            app.A = MultiRunAnalysis('RunList',str2num(app.RunDropDown.Value),...
                'AnaFlag','StackPixel',...
                'fitter',app.FitterSwitch.Value,...
                'AnaFlag','StackPixel',...
                'chi2',app.chi2,...
                'ringCutFlag','ex2',...
                'i_mnu',app.NeutrinomasssquaredeV2EditField.Value-app.A.ModelObj.mnuSq_i,...
                'i_Q',app.EndpointE0eVEditField.Value-app.A.ModelObj.Q_i,...
                'i_B',app.BackgroundcpsEditField.Value-app.A.ModelObj.BKG_RateSec_i,...
                'i_DTGS',app.GroundStateProbabilityEditField.Value-app.A.ModelObj.DTNormGS_i,...
                'i_N',app.NormalizationTritiumEditField.Value-1,...
                'fixPar',app.FixedParametersEditField.Value,...
                'exclDataStart',round(app.LowerqUE0binVSlider.Value),...
                'DataEffCorr',app.EfficiencycorrectionKnob.Value,...
                'pulls',app.Pulls);
            app.A.Fit;
            
            % Display Fit Results
            app.FitEditField_1.Value  = app.A.ModelObj.mnuSq_i+app.A.FitResult.par(1);
            app.FitEditField_2.Value  = app.A.ModelObj.Q_i+app.A.FitResult.par(2);
            app.FitEditField_3.Value  = app.A.ModelObj.BKG_RateSec_i+app.A.FitResult.par(3);
            app.FitEditField_4.Value  = 1+app.A.FitResult.par(4);
            app.FitEditField_5.Value  = app.A.ModelObj.DTNormGS_i + app.A.FitResult.par(2*app.A.ModelObj.nPixels+3);
            app.FitEditField_6.Value  = app.A.FitResult.err(1);
            app.FitEditField_7.Value  = app.A.FitResult.err(2);
            app.FitEditField_8.Value  = app.A.FitResult.err(3);
            app.FitEditField_9.Value  = app.A.FitResult.err(4);
            app.FitEditField_10.Value = app.A.FitResult.err(5);
            app.chisquareEditField.Value = app.A.FitResult.chi2min;
            app.DOFEditField.Value    = app.A.FitResult.dof;
            app.pvalueEditField.Value = chi2pvalue(app.A.FitResult.chi2min,app.A.FitResult.dof);
            
            app.PlotFit_DataModel;
            app.PlotFit_CorrelationMatrix;
            
            set(app.PlotFitResultsButton, 'Enable', 'on')
            set(app.PlotResidualsButton, 'Enable', 'on')
            
        end

        % Callback function
        function ModeListBoxValueChanged(app, event)
            value = app.ModeListBox.Value;
        end

        % Value changed function: LowerqUE0binVSlider
        function LowerqUE0binVSliderValueChanged(app, event)
            value = app.LowerqUE0binVSlider.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
            
        end

        % Callback function
        function EfficiencyCorrectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.EfficiencyCorrectionButtonGroup.SelectedObject;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: FitterSwitch
        function FitterSwitchValueChanged(app, event)
            value = app.FitterSwitch.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
            
        end

        % Value changed function: EfficiencycorrectionKnob
        function EfficiencycorrectionKnobValueChanged(app, event)
            value = app.EfficiencycorrectionKnob.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: NeutrinomasssquaredeV2EditField
        function NeutrinomasssquaredeV2EditFieldValueChanged(app, event)
            value = app.NeutrinomasssquaredeV2EditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: EndpointE0eVEditField
        function EndpointE0eVEditFieldValueChanged(app, event)
            value = app.EndpointE0eVEditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: BackgroundcpsEditField
        function BackgroundcpsEditFieldValueChanged(app, event)
            value = app.BackgroundcpsEditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: NormalizationTritiumEditField
        function NormalizationTritiumEditFieldValueChanged(app, event)
            value = app.NormalizationTritiumEditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: GroundStateProbabilityEditField
        function GroundStateProbabilityEditFieldValueChanged(app, event)
            value = app.GroundStateProbabilityEditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: Fix1
        function Fix1ValueChanged(app, event)
            value = app.Fix1.Value;
            if value
                app.FixParStr1 = ' 1';
            else
                app.FixParStr1 = '';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: Fix2
        function Fix2ValueChanged(app, event)
            value = app.Fix2.Value;
            if value
                app.FixParStr2 = ' 2';
            else
                app.FixParStr2 = '';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: Fix3
        function Fix3ValueChanged(app, event)
            value = app.Fix3.Value;
            if value
                app.FixParStr3 = ' 3';
            else
                app.FixParStr3 = '';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: Fix4
        function Fix4ValueChanged(app, event)
            value = app.Fix4.Value;
            if value == 1
                app.FixParStr4 = ' 4';
            else
                app.FixParStr4 = '';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: Fix5
        function Fix5ValueChanged(app, event)
            value = app.Fix5.Value;
            if value
                app.FixParStr5 = ' 5 6';
            else
                app.FixParStr5 = '';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            app.startupFcn;
        end

        % Value changed function: NuMassPullEditField
        function NuMassPullEditFieldValueChanged(app, event)
            value = app.NuMassPullEditField.Value;
            app.Pulls = [value,inf(1,app.A.ModelObj.nPixels*2+3)];
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: SystematicsSwitch
        function SystematicsSwitchValueChanged(app, event)
            value = app.SystematicsSwitch.Value;
            if strcmp(value,'On')
                app.chi2 = 'chi2CM';
            else
                app.chi2 = 'chi2Stat';
            end
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: rhoDEditFieldCell
        function rhoDEditFieldCellValueChanged(app, event)
            value = app.rhoDEditFieldCell.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: DTEditFieldCell
        function DTEditFieldCellValueChanged(app, event)
            value = app.DTEditFieldCell.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Value changed function: TTEditFieldCell
        function TTEditFieldCellValueChanged(app, event)
            value = app.TTEditFieldCell.Value;
            set(app.PlotFitResultsButton, 'Enable', 'off')
            set(app.PlotResidualsButton, 'Enable', 'off')
        end

        % Button pushed function: PlotFitResultsButton
        function PlotFitResultsButtonPushed(app, event)
            set(app.UIAxes,'yscale','log');
            app.PlotFit_DataModel;
        end

        % Button pushed function: PlotResidualsButton
        function PlotResidualsButtonPushed(app, event)
            set(app.UIAxes,'yscale','lin');
            app.PlotFit_Residual;
        end

        % Value changed function: pvalueEditField
        function pvalueEditFieldValueChanged(app, event)
            value = app.pvalueEditField.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'lin'
                    set(app.UIAxes,'yscale','lin');
                case 'log'
                    set(app.UIAxes,'yscale','log');
            end
        end

        % Value changed function: RunDropDown
        function RunDropDownValueChanged(app, event)
            value = app.RunDropDown.Value;
            startupFcn(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iSamakUIFigure = uifigure;
            app.iSamakUIFigure.Name = 'i-Samak';
            app.iSamakUIFigure.ButtonDownFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton = uibutton(app.iSamakUIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.PlotFitResultsButton = uibutton(app.iSamakUIFigure, 'push');
            app.PlotFitResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFitResultsButtonPushed, true);
            app.PlotFitResultsButton.Text = 'Plot Fit Results';
            app.FixedParametersEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.FixedParametersEditFieldLabel.Visible = 'off';
            app.FixedParametersEditFieldLabel.Text = 'Fixed Parameters';
            app.FixedParametersEditField = uieditfield(app.iSamakUIFigure, 'text');
            app.FixedParametersEditField.Visible = 'off';
            app.LowerqUE0binVSliderLabel = uilabel(app.iSamakUIFigure);
            app.LowerqUE0binVSliderLabel.Text = 'Lower qU-E0 bin (V)';
            app.LowerqUE0binVSlider = uislider(app.iSamakUIFigure);
            app.LowerqUE0binVSlider.Limits = [1 24];
            app.LowerqUE0binVSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24];
            app.LowerqUE0binVSlider.MajorTickLabels = {'1602  ', '1402 ', ' 1204 ', '1002  ', ' 804  ', ' 602  ', ' 402  ', '304  ', ' 202   ', '177  ', ' 154  ', '129  ', ' 104  ', '  92  ', '  82   ', ' 72  ', '  62   ', ' 52   ', ' 45  ', ' 32  ', '  22 ', '   15 ', '    2', '    -6'};
            app.LowerqUE0binVSlider.ValueChangedFcn = createCallbackFcn(app, @LowerqUE0binVSliderValueChanged, true);
            app.LowerqUE0binVSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24];
            app.LowerqUE0binVSlider.Value = 7;
            app.FitterSwitchLabel = uilabel(app.iSamakUIFigure);
            app.FitterSwitchLabel.Text = 'Fitter';
            app.FitterSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.FitterSwitch.Items = {'minuit', 'matlab'};
            app.FitterSwitch.ValueChangedFcn = createCallbackFcn(app, @FitterSwitchValueChanged, true);
            app.FitterSwitch.Value = 'minuit';
            app.EfficiencycorrectionKnobLabel = uilabel(app.iSamakUIFigure);
            app.EfficiencycorrectionKnobLabel.Text = 'Efficiency correction';
            app.EfficiencycorrectionKnob = uiknob(app.iSamakUIFigure, 'discrete');
            app.EfficiencycorrectionKnob.Items = {'OFF', 'ROI', 'PileUp', 'ROI+PileUp'};
            app.EfficiencycorrectionKnob.ValueChangedFcn = createCallbackFcn(app, @EfficiencycorrectionKnobValueChanged, true);
            app.EfficiencycorrectionKnob.Value = 'OFF';
            app.TextArea = uitextarea(app.iSamakUIFigure);
            app.TextArea.Value = {'KATRIN Experiment - First Tritium Data  - Single-run Fitter (Samak)'};
            app.NeutrinomasssquaredeV2Label = uilabel(app.iSamakUIFigure);
            app.NeutrinomasssquaredeV2Label.Text = '1:Neutrino mass squared (eV^2)';
            app.NeutrinomasssquaredeV2EditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.NeutrinomasssquaredeV2EditField.ValueChangedFcn = createCallbackFcn(app, @NeutrinomasssquaredeV2EditFieldValueChanged, true);
            app.EndpointE0eVEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.EndpointE0eVEditFieldLabel.Text = '2:Endpoint (E0, eV)';
            app.EndpointE0eVEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.EndpointE0eVEditField.ValueDisplayFormat = '%g';
            app.EndpointE0eVEditField.ValueChangedFcn = createCallbackFcn(app, @EndpointE0eVEditFieldValueChanged, true);
            app.BackgroundcpsLabel = uilabel(app.iSamakUIFigure);
            app.BackgroundcpsLabel.Text = '3:Background (cps)';
            app.BackgroundcpsEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.BackgroundcpsEditField.ValueDisplayFormat = '%g';
            app.BackgroundcpsEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundcpsEditFieldValueChanged, true);
            app.NormalizationTritiumLabel = uilabel(app.iSamakUIFigure);
            app.NormalizationTritiumLabel.Text = '4:Normalization (Tritium) ';
            app.NormalizationTritiumEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.NormalizationTritiumEditField.ValueDisplayFormat = '%g';
            app.NormalizationTritiumEditField.ValueChangedFcn = createCallbackFcn(app, @NormalizationTritiumEditFieldValueChanged, true);
            app.GroundStateProbabilityLabel = uilabel(app.iSamakUIFigure);
            app.GroundStateProbabilityLabel.Text = '5:Ground State Probability ';
            app.GroundStateProbabilityEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.GroundStateProbabilityEditField.ValueDisplayFormat = '%.3g';
            app.GroundStateProbabilityEditField.ValueChangedFcn = createCallbackFcn(app, @GroundStateProbabilityEditFieldValueChanged, true);
            app.InitLabel = uilabel(app.iSamakUIFigure);
            app.InitLabel.Text = 'Init';
            app.FitLabel = uilabel(app.iSamakUIFigure);
            app.FitLabel.Text = 'Fit';
            app.FitEditField_1 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_1.ValueDisplayFormat = '%.3f';
            app.FitEditField_1.ValueChangedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitEditField_1.Editable = 'off';
            app.FitEditField_2 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_2.ValueDisplayFormat = '%.2f';
            app.FitEditField_2.Editable = 'off';
            app.FitEditField_3 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_3.ValueDisplayFormat = '%.2f';
            app.FitEditField_3.Editable = 'off';
            app.FitEditField_4 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_4.ValueDisplayFormat = '%.3f';
            app.FitEditField_4.Editable = 'off';
            app.FitEditField_5 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_5.ValueDisplayFormat = '%.3g';
            app.FitEditField_5.Editable = 'off';
            app.ErrorLabel = uilabel(app.iSamakUIFigure);
            app.ErrorLabel.Text = 'Error';
            app.FitEditField_6 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_6.ValueDisplayFormat = '%.3f';
            app.FitEditField_6.Editable = 'off';
            app.FitEditField_7 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_7.ValueDisplayFormat = '%.2f';
            app.FitEditField_7.Editable = 'off';
            app.FitEditField_8 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_8.ValueDisplayFormat = '%.2f';
            app.FitEditField_8.Editable = 'off';
            app.FitEditField_9 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_9.ValueDisplayFormat = '%.3f';
            app.FitEditField_9.Editable = 'off';
            app.FitEditField_10 = uieditfield(app.iSamakUIFigure, 'numeric');
            app.FitEditField_10.ValueDisplayFormat = '%.2f';
            app.FitEditField_10.Editable = 'off';
            app.chisquareEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.chisquareEditFieldLabel.Text = 'chisquare';
            app.chisquareEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.chisquareEditField.ValueDisplayFormat = '%.1f';
            app.DOFEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.DOFEditFieldLabel.Text = 'DOF';
            app.DOFEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.DOFEditField.ValueDisplayFormat = '%g';
            app.Fix1 = uicheckbox(app.iSamakUIFigure);
            app.Fix1.ValueChangedFcn = createCallbackFcn(app, @Fix1ValueChanged, true);
            app.Fix1.Text = '';
            app.Fix1.Value = true;
            app.FixLabel = uilabel(app.iSamakUIFigure);
            app.FixLabel.Text = 'Fix';
            app.PullLabel = uilabel(app.iSamakUIFigure);
            app.PullLabel.Text = 'Pull';
            app.Fix2 = uicheckbox(app.iSamakUIFigure);
            app.Fix2.ValueChangedFcn = createCallbackFcn(app, @Fix2ValueChanged, true);
            app.Fix2.Text = '';
            app.Fix3 = uicheckbox(app.iSamakUIFigure);
            app.Fix3.ValueChangedFcn = createCallbackFcn(app, @Fix3ValueChanged, true);
            app.Fix3.Text = '';
            app.Fix4 = uicheckbox(app.iSamakUIFigure);
            app.Fix4.ValueChangedFcn = createCallbackFcn(app, @Fix4ValueChanged, true);
            app.Fix4.Text = '';
            app.Fix5 = uicheckbox(app.iSamakUIFigure);
            app.Fix5.ValueChangedFcn = createCallbackFcn(app, @Fix5ValueChanged, true);
            app.Fix5.Text = '';
            app.Fix5.Value = true;
            app.InitializationButton = uibutton(app.iSamakUIFigure, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Text = 'Initialization';
            app.NuMassPullEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.NuMassPullEditField.ValueChangedFcn = createCallbackFcn(app, @NuMassPullEditFieldValueChanged, true);
            app.NuMassPullEditField.Value = Inf;
            app.eV2Label = uilabel(app.iSamakUIFigure);
            app.eV2Label.Text = 'eV^2';
            app.SystematicsSwitchLabel = uilabel(app.iSamakUIFigure);
            app.SystematicsSwitchLabel.Text = 'Systematics';
            app.SystematicsSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.SystematicsSwitch.ValueChangedFcn = createCallbackFcn(app, @SystematicsSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TimeEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%g';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Editable = 'off';
            app.DTEditField = uilabel(app.iSamakUIFigure);
            app.DTEditField.Text = 'DT';
            app.DTEditFieldCell = uieditfield(app.iSamakUIFigure, 'numeric');
            app.DTEditFieldCell.ValueChangedFcn = createCallbackFcn(app, @DTEditFieldCellValueChanged, true);
            app.DTEditFieldCell.Editable = 'off';
            app.TTEditField = uilabel(app.iSamakUIFigure);
            app.TTEditField.Text = 'TT';
            app.TTEditFieldCell = uieditfield(app.iSamakUIFigure, 'numeric');
            app.TTEditFieldCell.ValueChangedFcn = createCallbackFcn(app, @TTEditFieldCellValueChanged, true);
            app.TTEditFieldCell.Editable = 'off';
            app.HTEditField = uilabel(app.iSamakUIFigure);
            app.HTEditField.Text = 'HT';
            app.HTEditFieldCell = uieditfield(app.iSamakUIFigure, 'numeric');
            app.HTEditFieldCell.Editable = 'off';
            app.rhoDEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.rhoDEditFieldLabel.Text = 'rhoD';
            app.rhoDEditFieldCell = uieditfield(app.iSamakUIFigure, 'numeric');
            app.rhoDEditFieldCell.ValueDisplayFormat = '%.5g';
            app.rhoDEditFieldCell.ValueChangedFcn = createCallbackFcn(app, @rhoDEditFieldCellValueChanged, true);
            app.rhoDEditFieldCell.Editable = 'off';
            app.secondLabel = uilabel(app.iSamakUIFigure);
            app.secondLabel.Text = 'second';
            app.molcm2Label = uilabel(app.iSamakUIFigure);
            app.molcm2Label.Text = 'mol/cm^2';
            app.Label = uilabel(app.iSamakUIFigure);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.iSamakUIFigure);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.iSamakUIFigure);
            app.Label_3.Text = '%';
            app.PlotResidualsButton = uibutton(app.iSamakUIFigure, 'push');
            app.PlotResidualsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResidualsButtonPushed, true);
            app.PlotResidualsButton.Text = 'Plot Residuals';
            app.pvalueEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.pvalueEditField.ValueDisplayFormat = '%.3g';
            app.pvalueEditField.ValueChangedFcn = createCallbackFcn(app, @pvalueEditFieldValueChanged, true);
            app.pvalueEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.mLabel = uilabel(app.iSamakUIFigure);
            app.mLabel.Text = 'm';
            app.E0Label = uilabel(app.iSamakUIFigure);
            app.E0Label.Text = 'E0';
            app.BLabel = uilabel(app.iSamakUIFigure);
            app.BLabel.Text = 'B';
            app.NLabel = uilabel(app.iSamakUIFigure);
            app.NLabel.Text = 'N';
            app.PgsLabel = uilabel(app.iSamakUIFigure);
            app.PgsLabel.Text = 'Pgs';
            app.Label_4 = uilabel(app.iSamakUIFigure);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.iSamakUIFigure);
            app.Label_5.Text = '-1';
            app.Switch = uiswitch(app.iSamakUIFigure, 'rocker');
            app.Switch.Items = {'lin', 'log'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'log';
            app.RunDropDownLabel = uilabel(app.iSamakUIFigure);
            app.RunDropDownLabel.Text = 'Run';
            app.RunDropDown = uidropdown(app.iSamakUIFigure);
            app.RunDropDown.Items = {'40538', '40539', '       40540', '       40541', '       40542', '       40543', '       40603', '       40604', '       40610', '       40611', '       40612', '       40613', '       40667', '       40668', '       40669', '       40670', '       40671', '       40672', '       40673', '       40674', '       40675', '       40676', '       40677', '       40678', '       40679', '       40680', '       40681', '       40682', '       40683', '       40684', '       40685', '       40686', '       40687', '       40688', '       40689', '       40690', '       40691', '       40692', '       40693', '       40763', '       40764', '       40765', '       40766', '       40769', '       40770', '       40771', '       40772', '       40773', '       40794', '       40795', '       40796', '       40797', '       40798', '       40799', '       40800', '       40801', '       40802', '       40803', '       40804', '       40805', '       40806', '       40807', '       40926', '       40927', '       40928', '       40929', '       40930', '       40931', '       40932', '       40933', '       40934', '       40935', '       40976', '       40977', '       40979', '       40980', '       40982', '       40983', '       40985', '       40986', '       40988', '       40989', '       40991', '       40992', '       40994', '       40995', '       40997', '       40998', '       41001', '       41002', '       41003', '       41004', '       41005', '       41006', '       41007', '       41008', '       41009', '       41011', '       41012'};
            app.RunDropDown.ValueChangedFcn = createCallbackFcn(app, @RunDropDownValueChanged, true);
            app.RunDropDown.Value = '40538';
        end
    end

    methods (Access = public)

        % Construct app
        function app = iSamakKATRINftFitter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iSamakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iSamakUIFigure)
        end
    end
end