classdef iSamakKATRINmaceTF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iSamakUIFigure                 matlab.ui.Figure
        relativisticTFSwitchLabel      matlab.ui.control.Label
        relativisticTFSwitch           matlab.ui.control.Switch
        synchrotronlossmeVSliderLabel  matlab.ui.control.Label
        synchrotronlossmeVSlider       matlab.ui.control.Slider
        BaGSliderLabel                 matlab.ui.control.Label
        BaGSlider                      matlab.ui.control.Slider
        BmTSliderLabel                 matlab.ui.control.Label
        BmTSlider                      matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
        PixelEditFieldLabel            matlab.ui.control.Label
        PixelEditField                 matlab.ui.control.NumericEditField
        BsTSliderLabel                 matlab.ui.control.Label
        BsTSlider                      matlab.ui.control.Slider
        KATRINMACEFilterTransmissionProbabilityTFLabel  matlab.ui.control.Label
        holdaxesSwitchLabel            matlab.ui.control.Label
        holdaxesSwitch                 matlab.ui.control.Switch
        LineWidthLabel                 matlab.ui.control.Label
        LineWidthEditField             matlab.ui.control.NumericEditField
        ColorDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        gammaFacApprox; % Factor to convert TF into relativistic TF
        TF; % Transmission function of MACE Filter
        ModelObj; % TBD Object
        TheoRes % Description
    end
    
    methods (Access = private)
        
        function PlotTF(app)
            hold(app.UIAxes,app.holdaxesSwitch.Value)
            plot(app.UIAxes,app.ModelObj.Te-app.ModelObj.qU(9),app.TF,...
                'LineWidth',app.LineWidthEditField.Value,'Color',rgb(app.ColorDropDown.Value));
            xlim(app.UIAxes,[-0.1 app.TheoRes])
            ylim(app.UIAxes,[-0.1 1.1])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            switch app.relativisticTFSwitch.Value
                case 'on'
                    app.gammaFacApprox = 1.018;
                case 'off'
                    app.gammaFacApprox = 1.;
            end
            app.ModelObj =ref_TBD_NominalKATRIN('FPD_PixList',app.PixelEditField.Value,...
                'nTeBinningFactor',100,...
                'WGTS_B_T',app.BsTSlider.Value,...
                'MACE_Bmax_T',app.BmTSlider.Value,...
                'MACE_Ba_T',app.BaGSlider.Value*1e-4);
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            % Init Plot
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
            xlabel(app.UIAxes,'surplus energy (eV)');
            ylabel(app.UIAxes,'transmission probability');
        end

        % Value changed function: synchrotronlossmeVSlider
        function synchrotronlossmeVSliderValueChanged(app, event)
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.PlotTF();
        end

        % Value changed function: relativisticTFSwitch
        function relativisticTFSwitchValueChanged(app, event)
            value = app.relativisticTFSwitch.Value;
            switch value
                case 'on'
                    app.gammaFacApprox = 1.018;
                case 'off'
                    app.gammaFacApprox = 1.;
            end
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
        end

        % Value changed function: BaGSlider
        function BaGSliderValueChanged(app, event)
            app.ModelObj =ref_TBD_NominalKATRIN('FPD_PixList',app.PixelEditField.Value,...
                'nTeBinningFactor',100,...
                'WGTS_B_T',app.BsTSlider.Value,...
                'MACE_Bmax_T',app.BmTSlider.Value,...
                'MACE_Ba_T',app.BaGSlider.Value*1e-4);
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
        end

        % Value changed function: BsTSlider
        function BsTSliderValueChanged(app, event)
            app.ModelObj =ref_TBD_NominalKATRIN('FPD_PixList',app.PixelEditField.Value,...
                'nTeBinningFactor',100,...
                'WGTS_B_T',app.BsTSlider.Value,...
                'MACE_Bmax_T',app.BmTSlider.Value,...
                'MACE_Ba_T',app.BaGSlider.Value*1e-4);
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
        end

        % Value changed function: BmTSlider
        function BmTSliderValueChanged(app, event)
            if app.BmTSlider.Value<app.BsTSlider.Value
                app.BmTSlider.Value=app.BsTSlider.Value;
            end
            app.ModelObj =ref_TBD_NominalKATRIN('FPD_PixList',app.PixelEditField.Value,...
                'nTeBinningFactor',100,...
                'WGTS_B_T',app.BsTSlider.Value,...
                'MACE_Bmax_T',app.BmTSlider.Value,...
                'MACE_Ba_T',app.BaGSlider.Value*1e-4);
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
        end

        % Value changed function: PixelEditField
        function PixelEditFieldValueChanged(app, event)
            app.ModelObj =ref_TBD_NominalKATRIN('FPD_PixList',app.PixelEditField.Value,...
                'nTeBinningFactor',100,...
                'WGTS_B_T',app.BsTSlider.Value,...
                'MACE_Bmax_T',app.BmTSlider.Value,...
                'MACE_Ba_T',app.BaGSlider.Value*1e-4);
            app.TF=app.ModelObj.ComputeMaceTF(app.ModelObj.Te,app.ModelObj.qU(9),...
                'pixel',app.PixelEditField.Value,...
                'SynchrotronLoss',1e-99+app.synchrotronlossmeVSlider.Value*1e-3,...
                'gammaFacApprox',app.gammaFacApprox);
            app.TheoRes = app.BaGSlider.Value*1e-4/app.BmTSlider.Value*app.ModelObj.qU(9)*1.2;
            app.PlotTF();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iSamakUIFigure = uifigure('Visible', 'off');
            app.iSamakUIFigure.Name = 'i-Samak';
            app.relativisticTFSwitchLabel = uilabel(app.iSamakUIFigure);
            app.relativisticTFSwitchLabel.Text = 'relativistic TF';
            app.relativisticTFSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.relativisticTFSwitch.Items = {'off', 'on'};
            app.relativisticTFSwitch.ValueChangedFcn = createCallbackFcn(app, @relativisticTFSwitchValueChanged, true);
            app.relativisticTFSwitch.Value = 'off';
            app.synchrotronlossmeVSliderLabel = uilabel(app.iSamakUIFigure);
            app.synchrotronlossmeVSliderLabel.Text = {'synchrotron '; 'loss (meV)'};
            app.synchrotronlossmeVSlider = uislider(app.iSamakUIFigure);
            app.synchrotronlossmeVSlider.ValueChangedFcn = createCallbackFcn(app, @synchrotronlossmeVSliderValueChanged, true);
            app.synchrotronlossmeVSlider.Value = 34;
            app.BaGSliderLabel = uilabel(app.iSamakUIFigure);
            app.BaGSliderLabel.Text = 'Ba (G)';
            app.BaGSlider = uislider(app.iSamakUIFigure);
            app.BaGSlider.Limits = [3 15];
            app.BaGSlider.ValueChangedFcn = createCallbackFcn(app, @BaGSliderValueChanged, true);
            app.BaGSlider.Value = 6;
            app.BmTSliderLabel = uilabel(app.iSamakUIFigure);
            app.BmTSliderLabel.Text = 'Bm (T)';
            app.BmTSlider = uislider(app.iSamakUIFigure);
            app.BmTSlider.Limits = [4 6];
            app.BmTSlider.ValueChangedFcn = createCallbackFcn(app, @BmTSliderValueChanged, true);
            app.BmTSlider.Value = 4.5;
            app.UIAxes = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'surplus energy (eV)')
            ylabel(app.UIAxes, 'transmission probability')
            app.PixelEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.PixelEditFieldLabel.Text = 'Pixel';
            app.PixelEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.PixelEditField.Limits = [1 148];
            app.PixelEditField.RoundFractionalValues = 'on';
            app.PixelEditField.ValueChangedFcn = createCallbackFcn(app, @PixelEditFieldValueChanged, true);
            app.PixelEditField.Value = 1;
            app.BsTSliderLabel = uilabel(app.iSamakUIFigure);
            app.BsTSliderLabel.Text = 'Bs (T)';
            app.BsTSlider = uislider(app.iSamakUIFigure);
            app.BsTSlider.Limits = [2 3.6];
            app.BsTSlider.ValueChangedFcn = createCallbackFcn(app, @BsTSliderValueChanged, true);
            app.BsTSlider.Value = 2.5;
            app.KATRINMACEFilterTransmissionProbabilityTFLabel = uilabel(app.iSamakUIFigure);
            app.KATRINMACEFilterTransmissionProbabilityTFLabel.Text = 'KATRIN MAC-E Filter Transmission Probability (TF)';
            app.holdaxesSwitchLabel = uilabel(app.iSamakUIFigure);
            app.holdaxesSwitchLabel.Text = 'hold axes';
            app.holdaxesSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.holdaxesSwitch.Items = {'off', 'on'};
            app.holdaxesSwitch.Value = 'off';
            app.LineWidthLabel = uilabel(app.iSamakUIFigure);
            app.LineWidthLabel.Text = {'Line'; ' Width'};
            app.LineWidthEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.LineWidthEditField.Limits = [1 148];
            app.LineWidthEditField.RoundFractionalValues = 'on';
            app.LineWidthEditField.Value = 5;
            app.ColorDropDown = uidropdown(app.iSamakUIFigure);
            app.ColorDropDown.Items = {'DarkBlue', 'FireBrick', 'DarkGreen', 'Orange', 'Red', 'Blue', 'CadetBlue', 'Gold'};
            app.ColorDropDown.ItemsData = {'DarkBlue', 'FireBrick', 'DarkGreen', 'Orange', 'Red', 'Blue', 'CadetBlue', 'Gold'};
            app.ColorDropDown.Value = 'DarkBlue';
            % Show the figure after all components are created
            app.iSamakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iSamakKATRINmaceTF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iSamakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iSamakUIFigure)
        end
    end
end