classdef MTDcreatorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iSamakUIFigure                 matlab.ui.Figure
        BackgroundFractionKnobLabel    matlab.ui.control.Label
        BackgroundFractionKnob         matlab.ui.control.Knob
        UIAxes                         matlab.ui.control.UIAxes
        NeutrinoMassBoostKnobLabel     matlab.ui.control.Label
        NeutrinoMassBoostKnob          matlab.ui.control.Knob
        EndpointeVEditFieldLabel       matlab.ui.control.Label
        EndpointeVEditField            matlab.ui.control.EditField
        BaGaussEditFieldLabel          matlab.ui.control.Label
        BaGaussEditField               matlab.ui.control.NumericEditField
        BfieldreductionEditFieldLabel  matlab.ui.control.Label
        BfieldreductionEditField       matlab.ui.control.NumericEditField
        RuntimedEditFieldLabel         matlab.ui.control.Label
        RuntimedEditField              matlab.ui.control.NumericEditField
        BackgroundListBoxLabel         matlab.ui.control.Label
        BackgroundListBox              matlab.ui.control.ListBox
        Switch                         matlab.ui.control.Switch
        ComputeSensitivityButton       matlab.ui.control.Button
        CLUpperLimitonNeutrinoMassmeVGaugeLabel  matlab.ui.control.Label
        CLUpperLimitonNeutrinoMassmeVGauge  matlab.ui.control.LinearGauge
        Label                          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        qUrangeeVSliderLabel           matlab.ui.control.Label
        qUrangeeVSlider                matlab.ui.control.Slider
        KATRINMeasurementTimeDistributionCreatorLabel  matlab.ui.control.Label
        MTDNameEditField               matlab.ui.control.EditField
        SaveMTDButton                  matlab.ui.control.Button
        LoadDropDownLabel              matlab.ui.control.Label
        LoadDropDown                   matlab.ui.control.DropDown
        logSwitch                      matlab.ui.control.Switch
        GBackgroundEditFieldLabel      matlab.ui.control.Label
        GBackgroundEditField           matlab.ui.control.NumericEditField
        GaugeBackground                matlab.ui.control.LinearGauge
        CLUpperLimitonNeutrinoMassmeVGaugeLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        TD;           % Time Distribution
        qU;           % Retarding Potential, eV
        qUfrac;       % Fraction of time per qU
        RunTimeSec;   % Run time, sec
        E0;           % endpoint, eV
        Range;        % range, eV
        BkgFraction;  %
        NuMassFactor; %
        MACE_Ba_G;    % Ba, Gauss
        BsBmRed;      % Field reduction
        BqU;          % Background
        mCL90;        % Nu Mass Sens
        AnchorBkg6G;% Background Anchor 6G 
    end
    
    methods (Access = private)
        
        function PlotMTDgui(app,varargin)
            
            if  app.RunTimeSec>0
                bar(app.UIAxes,app.qU-app.E0, app.qUfrac.*app.RunTimeSec,'blue');
                ylim(app.UIAxes,[0.5*min(app.qUfrac.*app.RunTimeSec),max(app.qUfrac.*app.RunTimeSec)]);
                ylabel(app.UIAxes, 'Time')
            else
                bar(app.UIAxes,app.qU-app.E0, app.qUfrac,'blue');
                ylim(app.UIAxes,[min(app.qUfrac) max(app.qUfrac)]);
                ylabel(app.UIAxes, 'Time Fraction')
                
            end
            set(app.UIAxes,'FontSize',14);
            xstr=sprintf('qU-%0.1f (V)',app.E0);
            xlabel(app.UIAxes, xstr)
            xlim(app.UIAxes,[min(app.qU-app.E0) max(app.qU-app.E0)]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.RunTimeSec   = 900*86400; % Run time, sec
            app.E0           = 18575; % endpoint, eV
            app.NuMassFactor = 60;
            app.BkgFraction  = 15;
            app.Range        = 30;
            app.MACE_Ba_G    = 7;
            app.BsBmRed      = 0.7;
            app.BqU          = '[5 10 20 30]';
            app.mCL90        = 1000;
            app.AnchorBkg6G  = 430e-3;
            app.GaugeBackground.Value =  GetBackground('MACE_Ba_T',app.MACE_Ba_G*1e-4,'WGTS_B_T',app.BsBmRed*3.6,'AnchorBkg6G',app.AnchorBkg6G)*1e3;
            app.qU           = [];
            app.qUfrac       = [];
            set(app.UIAxes,'yscale','log');
            set(app.UIAxes,'FontSize',14);
            xlim(app.UIAxes,[-min(app.Range+app.E0) max(app.BqU+app.E0)]);
            
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',...
                'AnchorBkg6G',app.AnchorBkg6G);
                
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changing function: BackgroundFractionKnob
        function BackgroundFractionKnobValueChanging(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.BkgFraction = event.Value;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',...
                ...  
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
         app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changing function: NeutrinoMassBoostKnob
        function NeutrinoMassBoostKnobValueChanging(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.NuMassFactor = event.Value;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                ...
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Callback function
        function qURangeVKnobValueChanged(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.Range = event.Value;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',...
                ... 
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
           app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changed function: EndpointeVEditField
        function EndpointeVEditFieldValueChanged(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.E0 = event.Value;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',...  
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changed function: BaGaussEditField
        function BaGaussEditFieldValueChanged(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            app.MACE_Ba_G = event.Value;
            app.GaugeBackground.Value =  GetBackground(...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'WGTS_B_T',app.BsBmRed*3.6,...
                'AnchorBkg6G',app.AnchorBkg6G)*1e3;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changed function: BfieldreductionEditField
        function BfieldreductionEditFieldValueChanged(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.BsBmRed = app.BfieldreductionEditField.Value;
            
            app.GaugeBackground.Value =  GetBackground(...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'WGTS_B_T',app.BsBmRed*3.6,...
                'AnchorBkg6G',app.AnchorBkg6G)*1e3;
            
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
           app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changed function: BackgroundListBox
        function BackgroundListBoxValueChanged(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            
            app.BqU = app.BackgroundListBox.Value;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',...  
                ...  
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Button pushed function: ComputeSensitivityButton
        function ComputeSensitivityButtonPushed(app, event)
            app.qU           = [];
            app.qUfrac       = [];
            app.Lamp.Color = 'green';
%                          [~,~, app.TD]  = MTDcreator(...
%                              'E0',app.E0,...
%                              'Range',app.Range,...
%                              'BqU',str2num(app.BqU),...
%                              'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
%                              'BsBmRed',app.BsBmRed,...
%                              'RunTime',app.RunTimeSec,...
%                              'NuMassFactor',app.NeutrinoMassBoostKnob.Value/100,...
%                               'BkgFraction',app.BackgroundFractionKnob.Value/100,...
%                               'Save','ON',...
%                               'MTD_Plot','OFF',...
%                               'NuMassSignal_Plot','OFF');
            S = SensitivityStudy('SysBudget','09','TD',app.TD,...
                'TD_BkgFraction',app.BackgroundFractionKnob.Value/100,...
                'TD_NuMassFactor',app.NeutrinoMassBoostKnob.Value/100,...
                'TD_BqU',str2num(app.BackgroundListBox.Value),...
                'chi2','chi2Stat','TimeSec',app.RunTimeSec,...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'WGTS_B_T',3.6*app.BsBmRed,...
                'MACE_Bmax_T',6*app.BsBmRed,...
                'Scan','ON','ScanPrcsn',0.01,...
                'AnchorBkg6G',app.AnchorBkg6G,...
                'range',app.Range,...
                'Q_i',str2num(app.EndpointeVEditField.Value));
             [mnuSq_i_Fit, par, err, tmp2, dof,m2CL90,mNumin] =  ...
                 S.NuMassScan;
             
%             [mnuSq_i_Fit, par, err, tmp2, dof,m2CL90,mNumin] =  ...
%                 NuMassScan_SensitivityNominal(...
%                 'SysBudget','07','TD',app.TD,...
%                 'chi2','chi2Stat','TimeSec',app.RunTimeSec,...
%                 'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
%                 'WGTS_B_T',3.6*app.BsBmRed,...
%                 'Scan','OFF','ScanPrcsn',0.02,...
%                 ...
%                 'AnchorBkg6G',app.AnchorBkg6G);

            app.mCL90 = 1e3*sqrt(m2CL90);
            app.CLUpperLimitonNeutrinoMassmeVGauge.Value = app.mCL90;
        end

        % Value changing function: qUrangeeVSlider
        function qUrangeeVSliderValueChanging(app, event)
            app.Range = round(event.Value,0);
            app.qU           = [];
            app.qUfrac       = [];
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                ...   
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();            
            app.Lamp.Color = 'red';
            app.MTDNameEditField.Value = ...
                sprintf('MTDcreator_E0%.1f_%.0feV_B%0.f_Ba%.1f_RedF%0.1f_NuMF%0.2f_BkgF%0.2f_B%.0f',...
            app.E0,app.Range,sum(str2num(app.BqU)),...
            app.MACE_Ba_G,app.BsBmRed,app.NuMassFactor/100,...
            app.BkgFraction/100,...
            app.GaugeBackground.Value);
        end

        % Value changed function: RuntimedEditField
        function RuntimedEditFieldValueChanged(app, event)
            value = app.RuntimedEditField.Value;
            app.RunTimeSec = app.RuntimedEditField.Value*86500;
            app.qU           = [];
            app.qUfrac       = [];
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                ...   
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
                        app.Lamp.Color = 'red';
        end

        % Button pushed function: SaveMTDButton
        function SaveMTDButtonPushed(app, event)
            qU=app.qU;
            disp(qU');
            qUfrac=app.qUfrac;
            disp(qUfrac');
            TD=app.MTDNameEditField.Value;
            TDname=sprintf('%s.mat',TD);
            save(TDname,'TD','qU','qUfrac');
            command=sprintf('mv %s ../../simulation/katrinsetup/TD_DataBank/',TDname);
            system(command);
        end

        % Value changed function: LoadDropDown
        function LoadDropDownValueChanged(app, event)
            app.TD = app.LoadDropDown.Value;
            MTD=load([app.TD '.mat']);
            app.qU=MTD.qU;
            app.qUfrac=MTD.qUfrac;
            app.PlotMTDgui();
            app.Lamp.Color = 'red';
        end

        % Value changed function: logSwitch
        function logSwitchValueChanged(app, event)
            value = app.logSwitch.Value;
            switch value
                case 'lin'
                    set(app.UIAxes,'yscale','lin');
                case 'log'
                    set(app.UIAxes,'yscale','log');
            end
        end

        % Value changed function: GBackgroundEditField
        function GBackgroundEditFieldValueChanged(app, event)
            value = app.GBackgroundEditField.Value;
            app.AnchorBkg6G = value*1e-3;
            app.GaugeBackground.Value =  GetBackground(...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'WGTS_B_T',app.BsBmRed*3.6,...
                'AnchorBkg6G',app.AnchorBkg6G)*1e3;
            [app.qU, app.qUfrac, app.TD] = MTDcreator(...
                'E0',app.E0,...
                'Range',app.Range,...
                'BqU',str2num(app.BqU),...
                'MACE_Ba_T',app.MACE_Ba_G*1e-4,...
                'BsBmRed',app.BsBmRed,...
                'RunTime',app.RunTimeSec,...
                'NuMassFactor',app.NuMassFactor/100,...
                'BkgFraction',app.BkgFraction/100,...
                'Save','OFF',...
                'MTD_Plot','OFF',...
                'NuMassSignal_Plot','OFF',... 
                'AnchorBkg6G',app.AnchorBkg6G);
            app.PlotMTDgui();
                        app.Lamp.Color = 'red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iSamakUIFigure = uifigure('Visible', 'off');
            app.iSamakUIFigure.Name = 'i-Samak';
            app.BackgroundFractionKnobLabel = uilabel(app.iSamakUIFigure);
            app.BackgroundFractionKnobLabel.Text = 'Background Fraction (%)';
            app.BackgroundFractionKnob = uiknob(app.iSamakUIFigure, 'continuous');
            app.BackgroundFractionKnob.ValueChangingFcn = createCallbackFcn(app, @BackgroundFractionKnobValueChanging, true);
            app.BackgroundFractionKnob.Value = 15;
            app.UIAxes = uiaxes(app.iSamakUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'qU-E0 (V)')
            ylabel(app.UIAxes, 'Time Fraction')
            app.NeutrinoMassBoostKnobLabel = uilabel(app.iSamakUIFigure);
            app.NeutrinoMassBoostKnobLabel.Text = 'Neutrino Mass Boost';
            app.NeutrinoMassBoostKnob = uiknob(app.iSamakUIFigure, 'continuous');
            app.NeutrinoMassBoostKnob.ValueChangingFcn = createCallbackFcn(app, @NeutrinoMassBoostKnobValueChanging, true);
            app.NeutrinoMassBoostKnob.Value = 60;
            app.EndpointeVEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.EndpointeVEditFieldLabel.Text = 'Endpoint (eV)';
            app.EndpointeVEditField = uieditfield(app.iSamakUIFigure, 'text');
            app.EndpointeVEditField.ValueChangedFcn = createCallbackFcn(app, @EndpointeVEditFieldValueChanged, true);
            app.EndpointeVEditField.Editable = 'off';
            app.EndpointeVEditField.Value = '18575';
            app.BaGaussEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.BaGaussEditFieldLabel.Text = 'Ba (Gauss)';
            app.BaGaussEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.BaGaussEditField.ValueChangedFcn = createCallbackFcn(app, @BaGaussEditFieldValueChanged, true);
            app.BaGaussEditField.Value = 7;
            app.BfieldreductionEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.BfieldreductionEditFieldLabel.Text = 'B-field reduction';
            app.BfieldreductionEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.BfieldreductionEditField.ValueChangedFcn = createCallbackFcn(app, @BfieldreductionEditFieldValueChanged, true);
            app.BfieldreductionEditField.Value = 0.7;
            app.RuntimedEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.RuntimedEditFieldLabel.Text = 'Run time (d)';
            app.RuntimedEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.RuntimedEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimedEditFieldValueChanged, true);
            app.RuntimedEditField.Value = 900;
            app.BackgroundListBoxLabel = uilabel(app.iSamakUIFigure);
            app.BackgroundListBoxLabel.Text = 'Background';
            app.BackgroundListBox = uilistbox(app.iSamakUIFigure);
            app.BackgroundListBox.Items = {'[5]', '[5 10]', '[5 10 20]', '[5 10 20 30]', '[5 10 20 30 50]', '[5 10 20 30 50 100]'};
            app.BackgroundListBox.ItemsData = {'[5]', '[5 10]', '[5 10 20]', '[5 10 20 30]', '[5 10 20 30 50]', '[5 10 20 30 50 100]'};
            app.BackgroundListBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundListBoxValueChanged, true);
            app.BackgroundListBox.Value = '[5 10 20 30]';
            app.Switch = uiswitch(app.iSamakUIFigure, 'slider');
            app.Switch.Items = {'Stat', 'Stat+Syst'};
            app.Switch.Enable = 'off';
            app.Switch.Value = 'Stat';
            app.ComputeSensitivityButton = uibutton(app.iSamakUIFigure, 'push');
            app.ComputeSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeSensitivityButtonPushed, true);
            app.ComputeSensitivityButton.Text = {'Compute'; 'Sensitivity'};
            app.CLUpperLimitonNeutrinoMassmeVGaugeLabel = uilabel(app.iSamakUIFigure);
            app.CLUpperLimitonNeutrinoMassmeVGaugeLabel.Text = '90%CL Upper Limit on Neutrino Mass - meV';
            app.CLUpperLimitonNeutrinoMassmeVGauge = uigauge(app.iSamakUIFigure, 'linear');
            app.CLUpperLimitonNeutrinoMassmeVGauge.Limits = [160 600];
            app.CLUpperLimitonNeutrinoMassmeVGauge.MajorTicks = [160 180 200 220 240 260 280 300 320 340 360 380 400 420 440 460 480 500 520 540 560 580 600];
            app.CLUpperLimitonNeutrinoMassmeVGauge.Value = 600;
            app.Label = uilabel(app.iSamakUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.iSamakUIFigure);
            app.qUrangeeVSliderLabel = uilabel(app.iSamakUIFigure);
            app.qUrangeeVSliderLabel.Text = 'qU range (eV)';
            app.qUrangeeVSlider = uislider(app.iSamakUIFigure);
            app.qUrangeeVSlider.Limits = [10 100];
            app.qUrangeeVSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.qUrangeeVSlider.MajorTickLabels = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.qUrangeeVSlider.ValueChangingFcn = createCallbackFcn(app, @qUrangeeVSliderValueChanging, true);
            app.qUrangeeVSlider.MinorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.qUrangeeVSlider.Value = 30;
            app.KATRINMeasurementTimeDistributionCreatorLabel = uilabel(app.iSamakUIFigure);
            app.KATRINMeasurementTimeDistributionCreatorLabel.Text = 'KATRIN: Measurement Time Distribution Creator ';
            app.MTDNameEditField = uieditfield(app.iSamakUIFigure, 'text');
            app.MTDNameEditField.Value = 'MTDcreatorTMP';
            app.SaveMTDButton = uibutton(app.iSamakUIFigure, 'push');
            app.SaveMTDButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMTDButtonPushed, true);
            app.SaveMTDButton.Text = 'Save MTD';
            app.LoadDropDownLabel = uilabel(app.iSamakUIFigure);
            app.LoadDropDownLabel.Text = 'Load';
            app.LoadDropDown = uidropdown(app.iSamakUIFigure);
            app.LoadDropDown.Items = {'Flat30', 'DR30', 'Sensitivity_30eV_Ba3G', 'Sensitivity_30eV_Ba4G', 'Sensitivity_30eV_Ba5G', 'Sensitivity_30eV_Ba6G', 'Sensitivity_30eV_Ba7G', 'Sensitivity_30eV_Ba8G', 'Sensitivity_30eV_Ba9G', 'Sensitivity_30eV_Ba10G', 'Sensitivity_60eV_Ba3G', 'Sensitivity_60eV_Ba4G', 'Sensitivity_60eV_Ba5G', 'Sensitivity_60eV_Ba6G', 'Sensitivity_60eV_Ba7G', 'Sensitivity_60eV_Ba8G', 'Sensitivity_60eV_Ba9G', 'Sensitivity_60eV_Ba10G', ''};
            app.LoadDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDropDownValueChanged, true);
            app.LoadDropDown.Value = 'DR30';
            app.logSwitch = uiswitch(app.iSamakUIFigure, 'slider');
            app.logSwitch.Items = {'log', 'lin'};
            app.logSwitch.ValueChangedFcn = createCallbackFcn(app, @logSwitchValueChanged, true);
            app.logSwitch.Value = 'log';
            app.GBackgroundEditFieldLabel = uilabel(app.iSamakUIFigure);
            app.GBackgroundEditFieldLabel.Text = '6G Background';
            app.GBackgroundEditField = uieditfield(app.iSamakUIFigure, 'numeric');
            app.GBackgroundEditField.Limits = [0 2000];
            app.GBackgroundEditField.RoundFractionalValues = 'on';
            app.GBackgroundEditField.ValueChangedFcn = createCallbackFcn(app, @GBackgroundEditFieldValueChanged, true);
            app.GBackgroundEditField.Value = 430;
            app.GaugeBackground = uigauge(app.iSamakUIFigure, 'linear');
            app.GaugeBackground.Limits = [0 500];
            app.GaugeBackground.Value = 335;
            app.CLUpperLimitonNeutrinoMassmeVGaugeLabel_2 = uilabel(app.iSamakUIFigure);
            app.CLUpperLimitonNeutrinoMassmeVGaugeLabel_2.Text = 'background (mcps)';
            % Show the figure after all components are created
            app.iSamakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTDcreatorGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iSamakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iSamakUIFigure)
        end
    end
end